classdef PSGpower_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PSGpowerLabel                   matlab.ui.control.Label
        PSGpowerVersionLabel            matlab.ui.control.Label
        Step1SelectfiledirectoryPanel   matlab.ui.container.Panel
        FileSelectListbox               matlab.ui.control.ListBox
        AddDirectoryforAnalysisButton   matlab.ui.control.Button
        IncludesubdirectoriesCheckBox   matlab.ui.control.CheckBox
        SelectfilestobeanalyzedwhileholdingCtrlthenclickLabel  matlab.ui.control.Label
        ChooseSubsetButton              matlab.ui.control.StateButton
        SessioninfocsvfoundUsetodeterminemontageandscoringSwitchLabel  matlab.ui.control.Label
        SessioninfocsvfoundUsetodeterminemontageandscoringSwitch  matlab.ui.control.Switch
        UpdateFileListButton            matlab.ui.control.Button
        DirectoryConfirmationLabel      matlab.ui.control.Label
        WhatsoftwarewasusedforsleepstagenotationDropDownLabel  matlab.ui.control.Label
        WhatsoftwarewasusedforsleepstagenotationDropDown  matlab.ui.control.DropDown
        EEGSamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        EEGSamplingFrequencyHzEditField  matlab.ui.control.EditField
        Step2SelectsignalprocessingapproachPanel  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        HypnogramTab                    matlab.ui.container.Tab
        HypnogramRasterPlotCheckbox     matlab.ui.control.CheckBox
        SpectraEEGLABTab                matlab.ui.container.Tab
        Apply60HznotchfilterbeforegeneratingspectraCheckBox  matlab.ui.control.CheckBox
        FrequencyrangeofinterestHzLabel_2  matlab.ui.control.Label
        PlotFreqROIHzMinimumEditField   matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        PlotFreqROIHzMaximumEditField   matlab.ui.control.NumericEditField
        TimeintervalforspectraButtonGroup  matlab.ui.container.ButtonGroup
        UsealldatainselectedstagesButton  matlab.ui.control.RadioButton
        CustomtimerangeminStartButton   matlab.ui.control.RadioButton
        CustomTimeIntervalStart         matlab.ui.control.NumericEditField
        CustomTimeIntervalEnd           matlab.ui.control.NumericEditField
        EndLabel                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        SpectraFieldTripTab             matlab.ui.container.Tab
        SpectraplotunitsDropDownLabel   matlab.ui.control.Label
        SpectraplotunitsDropDown        matlab.ui.control.DropDown
        TimeintervalforspectraButtonGroup_FT  matlab.ui.container.ButtonGroup
        UsealldatainselectedstagesButton_FT  matlab.ui.control.RadioButton
        CustomtimerangeminStartButton_FT  matlab.ui.control.RadioButton
        CustomTimeIntervalStart_FT      matlab.ui.control.NumericEditField
        CustomTimeIntervalEnd_FT        matlab.ui.control.NumericEditField
        EndLabel_FT                     matlab.ui.control.Label
        FrequencyrangeofinterestHzLabel  matlab.ui.control.Label
        PlotFreqROIMinimumEditField_FT  matlab.ui.control.NumericEditField
        toEditField_2Label_2            matlab.ui.control.Label
        PlotFreqROIMaximumEditField_FT  matlab.ui.control.NumericEditField
        Apply60HznotchfilterbeforegeneratingspectraCheckBox_FT  matlab.ui.control.CheckBox
        WindowlengthsecEditFieldLabel   matlab.ui.control.Label
        WindowlengthsecEditField_FT     matlab.ui.control.NumericEditField
        WindowoverlapproportionEditFieldLabel_2  matlab.ui.control.Label
        WindowoverlapproportionEditField_FT  matlab.ui.control.NumericEditField
        NoteLabelSpectra_FT             matlab.ui.control.Label
        PowerHilbertTab                 matlab.ui.container.Tab
        FiniteImpulseResponseFIRorderButtonGroup  matlab.ui.container.ButtonGroup
        HardcodedDefaultButton          matlab.ui.control.RadioButton
        CohensAlgorithmButton           matlab.ui.control.RadioButton
        ThetaFilterModeDropDownLabel    matlab.ui.control.Label
        ThetaFilterModeDropDown         matlab.ui.control.DropDown
        HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDownLabel  matlab.ui.control.Label
        HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown  matlab.ui.control.DropDown
        HilbertEnvelopesButtonGroup     matlab.ui.container.ButtonGroup
        CreatenewenvelopefilesButton    matlab.ui.control.RadioButton
        LoadexistingenvelopefilesButton  matlab.ui.control.RadioButton
        PowernewtimefTab                matlab.ui.container.Tab
        RejectartifactsfirstCheckBox    matlab.ui.control.CheckBox
        ofFrequencies5shortrecommended50mid999longEditFieldLabel  matlab.ui.control.Label
        ofFrequencies5shortrecommended50mid999longEditField  matlab.ui.control.NumericEditField
        WhatsizetemporalwindowshouldbeusedfornewtimefsecEditFieldLabel  matlab.ui.control.Label
        WhatsizetemporalwindowshouldbeusedfornewtimefsecEditField  matlab.ui.control.NumericEditField
        SpindleDetectionFerrarelliTab   matlab.ui.container.Tab
        Resampleto128HzCheckBox         matlab.ui.control.CheckBox
        Lowpasssigmaenvelopeat2HzCheckBox  matlab.ui.control.CheckBox
        DrawstagesofwaveformabstractionforFerrarellimethodCheckBox  matlab.ui.control.CheckBox
        WhatlowerupperthresholdratiostouseLowerThresholdLabel  matlab.ui.control.Label
        FerrarelliThresholdLow          matlab.ui.control.NumericEditField
        UpperThresholdLabel             matlab.ui.control.Label
        FerrarelliThresholdHigh         matlab.ui.control.NumericEditField
        NoteFerrarelli2010uses210whileMcClainetalBoulderused26Label  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        EnvelopeViewerTab               matlab.ui.container.Tab
        BaselineRemovalChoiceCheckBox   matlab.ui.control.CheckBox
        GlobalBaslineRemovalChoiceCheckBox  matlab.ui.control.CheckBox
        ArtifactNaNChoiceCheckBox       matlab.ui.control.CheckBox
        HowlongofawindowshouldbeusedforlocalbaselinesecLabel  matlab.ui.control.Label
        BaselineRemovalWindowLengthNumericEditField  matlab.ui.control.NumericEditField
        WhatartifacthandlingmethodshouldbeusedLabel  matlab.ui.control.Label
        ArtifactChoiceDropdown          matlab.ui.control.DropDown
        MedianSmoothWindowNumericEditFieldLabel  matlab.ui.control.Label
        MedianSmoothWindowNumericEditField  matlab.ui.control.NumericEditField
        QuantifyenvelopesButtonGroup    matlab.ui.container.ButtonGroup
        ByHourButton                    matlab.ui.control.RadioButton
        ByStageButton                   matlab.ui.control.RadioButton
        AverageenvelopesacrossButtonGroup  matlab.ui.container.ButtonGroup
        chansButton                     matlab.ui.control.RadioButton
        timeButton                      matlab.ui.control.RadioButton
        WhatenvelopetypeButtonGroup     matlab.ui.container.ButtonGroup
        amplitudeButton                 matlab.ui.control.RadioButton
        powerButton                     matlab.ui.control.RadioButton
        CollateorloadenvelopesButtonGroup  matlab.ui.container.ButtonGroup
        CollateButton                   matlab.ui.control.RadioButton
        LoadButton                      matlab.ui.control.RadioButton
        EnvViewerTempROILowNumericEditFieldLabel  matlab.ui.control.Label
        EnvViewerTempROILowNumericEditField  matlab.ui.control.NumericEditField
        EnvViewerTempROIHighNumericEditFieldLabel  matlab.ui.control.Label
        EnvViewerTempROIHighNumericEditField  matlab.ui.control.NumericEditField
        OmitEmptyEnvelopesCheckBox      matlab.ui.control.CheckBox
        ThetaFilterModeDropDown_EnvViewerLabel  matlab.ui.control.Label
        ThetaFilterModeDropDown_EnvViewer  matlab.ui.control.DropDown
        ModulespecificParametersLabel   matlab.ui.control.Label
        AnalysisModuleselectoneDropDownLabel  matlab.ui.control.Label
        AnalysisModuleselectoneDropDown  matlab.ui.control.DropDown
        Step3SelectbandsandstagesPanel  matlab.ui.container.Panel
        SubdeltaCheckBox                matlab.ui.control.CheckBox
        DeltaCheckBox                   matlab.ui.control.CheckBox
        ThetaCheckBox                   matlab.ui.control.CheckBox
        AlphaCheckBox                   matlab.ui.control.CheckBox
        SigmaCheckBox                   matlab.ui.control.CheckBox
        BetaCheckBox                    matlab.ui.control.CheckBox
        GammalowCheckBox                matlab.ui.control.CheckBox
        FullbandCheckBox                matlab.ui.control.CheckBox
        EEGBandsLabel                   matlab.ui.control.Label
        FrequencyBandwidthLabel         matlab.ui.control.Label
        SleepStagesLabel                matlab.ui.control.Label
        SubdeltaLowLimFreq              matlab.ui.control.NumericEditField
        DeltaLowLimFreq                 matlab.ui.control.NumericEditField
        ThetaLowLimFreq                 matlab.ui.control.NumericEditField
        AlphaLowLimFreq                 matlab.ui.control.NumericEditField
        SigmaLowLimFreq                 matlab.ui.control.NumericEditField
        BetaLowLimFreq                  matlab.ui.control.NumericEditField
        GammalowLowLimFreq              matlab.ui.control.NumericEditField
        FullbandLowLimFreq              matlab.ui.control.NumericEditField
        SubdeltatoLabel                 matlab.ui.control.Label
        DeltatoLabel_2                  matlab.ui.control.Label
        ThetatoLabel_3                  matlab.ui.control.Label
        AlphatoLabel_4                  matlab.ui.control.Label
        SigmatoLabel_5                  matlab.ui.control.Label
        BetatoLabel_6                   matlab.ui.control.Label
        GammalowtoLabel_7               matlab.ui.control.Label
        FullbandtoLabel                 matlab.ui.control.Label
        SubdeltaHighLimFreq             matlab.ui.control.NumericEditField
        DeltaHighLimFreq                matlab.ui.control.NumericEditField
        ThetaHighLimFreq                matlab.ui.control.NumericEditField
        AlphaHighLimFreq                matlab.ui.control.NumericEditField
        SigmaHighLimFreq                matlab.ui.control.NumericEditField
        BetaHighLimFreq                 matlab.ui.control.NumericEditField
        GammalowHighLimFreq             matlab.ui.control.NumericEditField
        FullbandHighLimFreq             matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        HzLabel_5                       matlab.ui.control.Label
        HzLabel_6                       matlab.ui.control.Label
        HzLabel_7                       matlab.ui.control.Label
        HzLabel_9                       matlab.ui.control.Label
        SubdeltaN1                      matlab.ui.control.CheckBox
        DeltaN1                         matlab.ui.control.CheckBox
        ThetaN1                         matlab.ui.control.CheckBox
        AlphaN1                         matlab.ui.control.CheckBox
        SigmaN1                         matlab.ui.control.CheckBox
        BetaN1                          matlab.ui.control.CheckBox
        GammalowN1                      matlab.ui.control.CheckBox
        FullbandN1                      matlab.ui.control.CheckBox
        N1Label                         matlab.ui.control.Label
        SubdeltaN2                      matlab.ui.control.CheckBox
        DeltaN2                         matlab.ui.control.CheckBox
        ThetaN2                         matlab.ui.control.CheckBox
        AlphaN2                         matlab.ui.control.CheckBox
        SigmaN2                         matlab.ui.control.CheckBox
        BetaN2                          matlab.ui.control.CheckBox
        GammalowN2                      matlab.ui.control.CheckBox
        FullbandN2                      matlab.ui.control.CheckBox
        N2Label                         matlab.ui.control.Label
        SubdeltaN3                      matlab.ui.control.CheckBox
        DeltaN3                         matlab.ui.control.CheckBox
        ThetaN3                         matlab.ui.control.CheckBox
        AlphaN3                         matlab.ui.control.CheckBox
        SigmaN3                         matlab.ui.control.CheckBox
        BetaN3                          matlab.ui.control.CheckBox
        GammalowN3                      matlab.ui.control.CheckBox
        FullbandN3                      matlab.ui.control.CheckBox
        N3Label                         matlab.ui.control.Label
        SubdeltaR                       matlab.ui.control.CheckBox
        DeltaR                          matlab.ui.control.CheckBox
        ThetaR                          matlab.ui.control.CheckBox
        AlphaR                          matlab.ui.control.CheckBox
        SigmaR                          matlab.ui.control.CheckBox
        BetaR                           matlab.ui.control.CheckBox
        GammalowR                       matlab.ui.control.CheckBox
        FullbandR                       matlab.ui.control.CheckBox
        RLabel                          matlab.ui.control.Label
        SubdeltaW                       matlab.ui.control.CheckBox
        DeltaW                          matlab.ui.control.CheckBox
        ThetaW                          matlab.ui.control.CheckBox
        AlphaW                          matlab.ui.control.CheckBox
        SigmaW                          matlab.ui.control.CheckBox
        BetaW                           matlab.ui.control.CheckBox
        GammalowW                       matlab.ui.control.CheckBox
        FullbandW                       matlab.ui.control.CheckBox
        WLabel                          matlab.ui.control.Label
        SubdeltaN4                      matlab.ui.control.CheckBox
        DeltaN4                         matlab.ui.control.CheckBox
        ThetaN4                         matlab.ui.control.CheckBox
        AlphaN4                         matlab.ui.control.CheckBox
        SigmaN4                         matlab.ui.control.CheckBox
        BetaN4                          matlab.ui.control.CheckBox
        GammalowN4                      matlab.ui.control.CheckBox
        FullbandN4                      matlab.ui.control.CheckBox
        N4Label                         matlab.ui.control.Label
        ArtifactThresholdLabel          matlab.ui.control.Label
        SubdeltaArtifact                matlab.ui.control.NumericEditField
        DeltaArtifact                   matlab.ui.control.NumericEditField
        ThetaArtifact                   matlab.ui.control.NumericEditField
        AlphaArtifact                   matlab.ui.control.NumericEditField
        SigmaArtifact                   matlab.ui.control.NumericEditField
        BetaArtifact                    matlab.ui.control.NumericEditField
        GammalowArtifact                matlab.ui.control.NumericEditField
        FullbandArtifact                matlab.ui.control.NumericEditField
        SleepbandCheckBox               matlab.ui.control.CheckBox
        SleepbandLowLimFreq             matlab.ui.control.NumericEditField
        SleepbandtoLabel                matlab.ui.control.Label
        SleepbandHighLimFreq            matlab.ui.control.NumericEditField
        HzLabel_10                      matlab.ui.control.Label
        SleepbandN1                     matlab.ui.control.CheckBox
        SleepbandN2                     matlab.ui.control.CheckBox
        SleepbandN3                     matlab.ui.control.CheckBox
        SleepbandR                      matlab.ui.control.CheckBox
        SleepbandW                      matlab.ui.control.CheckBox
        SleepbandN4                     matlab.ui.control.CheckBox
        SleepbandArtifact               matlab.ui.control.NumericEditField
        AllStagesButton                 matlab.ui.control.Button
        AllBandsButton                  matlab.ui.control.Button
        Step4OthersettingsPanel         matlab.ui.container.Panel
        ForcerecreationofEEGLABsetfilesCheckBox  matlab.ui.control.CheckBox
        DownsampletoHzCheckBox          matlab.ui.control.CheckBox
        SampRateisHighLabel             matlab.ui.control.Label
        RereferenceEEGDatatoButtonGroup  matlab.ui.container.ButtonGroup
        AvgMastButton                   matlab.ui.control.RadioButton
        ContraMastButton                matlab.ui.control.RadioButton
        CustomRerefButton               matlab.ui.control.RadioButton
        CustomRerefEditField            matlab.ui.control.EditField
        RunandsaveICAdecompositionsCheckBox  matlab.ui.control.CheckBox
        InterpolatemanuallyidentifiedbadchannelsCheckBox  matlab.ui.control.CheckBox
        DownsampledFrequencyNumericEditField  matlab.ui.control.NumericEditField
        ARBCISettingsLampLabel          matlab.ui.control.Label
        ARBCISettingsLamp               matlab.ui.control.Lamp
        ARBCISettingsButton             matlab.ui.control.Button
        PreprocessingSettingsButton     matlab.ui.control.Button
        PreProcessingSettingsLampLabel  matlab.ui.control.Label
        PreProcessingSettingsLamp       matlab.ui.control.Lamp
        InterpolateextrathetabadchannelsMRISRTonlyCheckBox  matlab.ui.control.CheckBox
        ImageModeButtonGroup            matlab.ui.container.ButtonGroup
        AutocloseButton                 matlab.ui.control.RadioButton
        PauseButton                     matlab.ui.control.RadioButton
        AnalyzeButton                   matlab.ui.control.Button
        ImportPSGpowerParametersButton  matlab.ui.control.Button
        ExportPSGpowerParametersButton  matlab.ui.control.Button
        ExperimentnameDropDownLabel     matlab.ui.control.Label
        ExperimentnameDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        % Variables coming from Step 1: Select Files for analysis
        eegfiles                % EEG file names
        eegrootfolder           % Primary EEG files directory
        subdirlist              % Subdirectory
        cursubdir               % Current subdirectory
        vhdrfiles               % .vhdr file names
        stagefp                 % staging filepaths
        stgfiles                % staging files
        txtfiles                % .txt file names
        matfiles                % .mat file names
        edffiles                % .edf file names
        EDFfiles                % .EDF file names
        arbci_settings          % ARBCI settings from .txt file
        preproc_settings        % Experiment-specific preprocessing settings
        tmrtistry_settings      % TMRtistry settings from .txt file
        tmrtistry_script        % Script to run TMRtistry from .txt file
        arthresh                % Structure to hold artifact thresholds for current experiment
        
        psgpowersettings_backup % PSGpower GUI state for printing in the diary log
        
    end
    
    methods (Access = private)
        
        function app = refreshGUI(app)
            %%%Set GUI element default states
            app.eegfiles = '';
            app.FileSelectListbox.Items = {};
            app.arbci_settings = '';
            app.preproc_settings = '';
            app.tmrtistry_settings = '';
            app.tmrtistry_script = '';
            
            %% Reset Step 1 component states
            app.IncludesubdirectoriesCheckBox.Enable = 'off';
            app.IncludesubdirectoriesCheckBox.Value = 0;
            app.EEGSamplingFrequencyHzEditField.Value = num2str(0);
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Visible = 'off';
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Value = 'No';
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitchLabel.Visible = 'off';
            app.DirectoryConfirmationLabel.Visible = 'off';
            app.WhatsoftwarewasusedforsleepstagenotationDropDown.Visible = 'off';
            app.WhatsoftwarewasusedforsleepstagenotationDropDown.Value = ' ';
            app.WhatsoftwarewasusedforsleepstagenotationDropDownLabel.Visible = 'off';
            app.EEGSamplingFrequencyHzEditField.Visible = 'off';
            app.EEGSamplingFrequencyHzEditFieldLabel.Visible = 'off';
            app.ChooseSubsetButton.Visible = 'off';
            app.UpdateFileListButton.Visible = 'off';
            app.SelectfilestobeanalyzedwhileholdingCtrlthenclickLabel.Visible = 'off';
            app.FileSelectListbox.Visible = 'off';
            
            %% Reset Step 2 component states
            app.Step2SelectsignalprocessingapproachPanel.Visible = 'off';
            app.AnalysisModuleselectoneDropDown.Value = ' ';
            app.TabGroup.Visible = 'off';

            %Step2-Hilbert
            app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown.Visible = 'off'; %?
            app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDownLabel.Visible = 'off'; %?
            %Step2-Envelope Viewer
            app.OmitEmptyEnvelopesCheckBox.Visible = 'off';
            app.BaselineRemovalWindowLengthNumericEditField.Visible = 'off';
            app.BaselineRemovalWindowLengthNumericEditField.Value = 0;
            app.HowlongofawindowshouldbeusedforlocalbaselinesecLabel.Visible = 'off';
            app.ThetaFilterModeDropDown_EnvViewer.Visible = 'off';
            app.ThetaFilterModeDropDown_EnvViewer.Value = 'none';
            app.ThetaFilterModeDropDown_EnvViewerLabel.Visible = 'off';
            app.MedianSmoothWindowNumericEditField.Visible = 'off';
            app.MedianSmoothWindowNumericEditField.Value = 90;
            app.MedianSmoothWindowNumericEditFieldLabel.Visible = 'off';
            app.EnvViewerTempROILowNumericEditField.Visible = 'off';
            app.EnvViewerTempROILowNumericEditField.Value = 0;
            app.EnvViewerTempROILowNumericEditFieldLabel.Visible = 'off';
            app.EnvViewerTempROIHighNumericEditField.Visible = 'off';
            app.EnvViewerTempROIHighNumericEditField.Value = 60;
            app.EnvViewerTempROIHighNumericEditFieldLabel.Visible = 'off';
            
            %% Reset Step 3 component states
            app.Step3SelectbandsandstagesPanel.Visible = 'off';
            
            %% Reset Step 4 component states
            app.Step4OthersettingsPanel.Visible = 'off';
            app.DownsampletoHzCheckBox.Visible = 'off';
            app.SampRateisHighLabel.Visible = 'off';
            app.DownsampledFrequencyNumericEditField.Visible = 'off';
            app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Visible = 'off';
            app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Value = 0;
            
            % Analyze button disabled at startup
            app.AnalyzeButton.Enable = 'off';
        end
        
        function app = setStageFreqDefaults(app, value)
            app.SubdeltaLowLimFreq.Value = 0.1;
            app.SubdeltaHighLimFreq.Value = 0.5;
            app.DeltaLowLimFreq.Value = 0.5;
            app.DeltaHighLimFreq.Value = 4;
            app.ThetaLowLimFreq.Value = 4;
            app.ThetaHighLimFreq.Value = 8;
            app.AlphaLowLimFreq.Value = 8;
            app.AlphaHighLimFreq.Value = 12;
            app.SigmaLowLimFreq.Value = 12;
            app.SigmaHighLimFreq.Value = 16;
            app.BetaLowLimFreq.Value = 16;
            app.BetaHighLimFreq.Value = 30;
            app.GammalowLowLimFreq.Value = 30;
            app.GammalowHighLimFreq.Value = 50;
            app.FullbandLowLimFreq.Value = 0;
            app.FullbandHighLimFreq.Value = 80;
            app.SleepbandLowLimFreq.Value = 0.5;
            app.SleepbandHighLimFreq.Value = 20;
        end
        
        function app = setARthreshDefaults(app, value)
            app.arthresh.subdelta = 250;
            app.arthresh.delta = 250;
            app.arthresh.theta = 150;
            app.arthresh.alpha = 100;
            app.arthresh.sigma = 75;
            app.arthresh.beta = 75;
            app.arthresh.gammalow = 75;
            app.arthresh.fullband = 250;
            app.arthresh.sleepband = 250;
        end
        
        function app = moduleGUIsetup(app, value)
            
            switch value
                case 'Hypnogram'
                    app.AnalysisModuleselectoneDropDown.Value = 'Hypnogram';
                    app.TabGroup.SelectedTab = app.HypnogramTab;
                    app = setStep3Bands(app, {'fullband'}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                    % Enable custom fullband parameters
                    app.FullbandLowLimFreq.Enable = 'off';
                    app.FullbandLowLimFreq.Value = 0;
                    app.FullbandHighLimFreq.Enable = 'off';
                    app.FullbandHighLimFreq.Value = 0;
                    app.FullbandArtifact.Enable = 'off';
                    
                case 'Spectra (EEGLAB)'
                    app.AnalysisModuleselectoneDropDown.Value = 'Spectra (EEGLAB)';
                    app.TabGroup.SelectedTab = app.SpectraEEGLABTab;
                    app = setStep3Bands(app, {'fullband'}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                    % Enable custom Fullband parameters
                    app.FullbandArtifact.Enable = 'off';
                    
                case 'Spectra (FieldTrip)'
                    app.AnalysisModuleselectoneDropDown.Value = 'Spectra (FieldTrip)';
                    app.TabGroup.SelectedTab = app.SpectraFieldTripTab;
                    app = setStep3Bands(app, {'fullband'}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                    % Enable custom Fullband parameters
                    app.FullbandArtifact.Enable = 'off';
                    
                case 'Spectra (newtimef)'
                    app.AnalysisModuleselectoneDropDown.Value = 'Spectra (newtimef)';
                    app.TabGroup.SelectedTab = app.SpectranewtimefTab;
                    app = setStep3Bands(app, {'fullband'}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                    % Enable custom Fullband parameters
                    app.FullbandArtifact.Enable = 'off';
                    
                case 'Power (Hilbert)'
                    app.AnalysisModuleselectoneDropDown.Value = 'Power (Hilbert)';
                    app.TabGroup.SelectedTab = app.PowerHilbertTab;
                    HilbertEnvelopesButtonGroupSelectionChanged(app);
                    %app = setStep3Bands(app, {'subdelta', 'delta', 'theta', 'sigma'}, 'othersOn', 1, 1, 1, 1, 1, 1);
                    %ThetaFilterModeDropDownValueChanged(app);
                    
                case 'Power (newtimef)'
                    app.AnalysisModuleselectoneDropDown.Value = 'Power (newtimef)';
                    app.TabGroup.SelectedTab = app.PowernewtimefTab;
                    app = setStep3Bands(app, {'subdelta', 'delta', 'theta', 'sigma'}, 'othersOn', 1, 1, 1, 1, 1, 1);
                    
                case 'Spindle Detection (Ferrarelli)'
                    app.AnalysisModuleselectoneDropDown.Value = 'Spindle Detection (Ferrarelli)';
                    app.TabGroup.SelectedTab = app.SpindleDetectionFerrarelliTab;
                    app = setStep3Bands(app, {'sigma'}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                    % Enable custom Sigmaband parameters
                    app.SigmaCheckBox.Enable = 'off';
                    app.SigmaLowLimFreq.Enable = 'off';
                    app.SigmaLowLimFreq.Value = 11;
                    app.SigmaHighLimFreq.Enable = 'off';
                    app.SigmaHighLimFreq.Value = 15;
                    app.SigmaN1.Enable = 'off';
                    app.SigmaN2.Enable = 'off';
                    app.SigmaN3.Enable = 'off';
                    app.SigmaN4.Enable = 'off';
                    app.SigmaR.Enable = 'off';
                    app.SigmaW.Enable = 'off';
                    app.SigmaArtifact.Enable = 'off';
                    
                case 'PAC (Muehlroth)'
                    app.AnalysisModuleselectoneDropDown.Value = 'PAC (Muehlroth)';
                    app.TabGroup.SelectedTab = app.PACMuehlrothTab;
                    app = setStep3Bands(app, {''}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                    % Maybe pull GUI Deltaband and Sigmaband bandpass etc for use in SO and SPN detection algorithms? - ABF 2022-02-23
                    
                case 'TMRtistry (WIP)'
                    app.AnalysisModuleselectoneDropDown.Value = 'TMRtistry (WIP)';
                    app.TabGroup.SelectedTab = app.TMRtistryWIPTab;
                    app = setStep3Bands(app, {''}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                case 'Envelope Viewer'
                    app.AnalysisModuleselectoneDropDown.Value = 'Envelope Viewer';
                    app.TabGroup.SelectedTab = app.EnvelopeViewerTab;
                    %app = setStep3Bands(app, {''}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                    CollateorloadenvelopesButtonGroupSelectionChanged(app, '')
                    
                case 'Spectrogram Viewer'
                    app.AnalysisModuleselectoneDropDown.Value = 'Spectrogram Viewer';
                    app.TabGroup.SelectedTab = app.SpectrogramViewerTab;
                    app = setStep3Bands(app, {''}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
                otherwise
                    app.TabGroup.Visible = 'off';
                    app.Step3SelectbandsandstagesPanel.Visible = 'off';
                    app.Step4OthersettingsPanel.Visible = 'off';
                    app.AnalyzeButton.Enable = 'off';
                    app = setStep3Bands(app, {''}, 'othersOff', 1, 1, 1, 1, 1, 1);
                    
            end
        end
        
        function app = setStep3Bands(app, enableList, disableOthers, resetParams, resetStages, enableCheckbox, enableFreqs, enableStages, enableAR)
            %General Step 3 setup
            if strcmp(disableOthers, 'othersOff')
                othersSetting = 'off';
            elseif strcmp(disableOthers, 'othersOn')
                othersSetting = 'on';
            end
            app.AllBandsButton.Enable = othersSetting;
            app.AllStagesButton.Enable = 'on';
            
            %Reset Step 3 settings that are altered by some module choices
            if resetParams == 1
                app = setStageFreqDefaults(app);
                
                app.SubdeltaArtifact.Value = app.arthresh.subdelta;
                app.DeltaArtifact.Value = app.arthresh.delta;
                app.ThetaArtifact.Value = app.arthresh.theta;
                app.AlphaArtifact.Value = app.arthresh.alpha;
                app.SigmaArtifact.Value = app.arthresh.sigma;
                app.BetaArtifact.Value = app.arthresh.beta;
                app.GammalowArtifact.Value = app.arthresh.gammalow;
                app.FullbandArtifact.Value = app.arthresh.fullband;
                app.SleepbandArtifact.Value = app.arthresh.sleepband;
            end
            
            %Disable all bands and stages
            allguibands = {'Subdelta', 'Delta', 'Theta', 'Sigma', 'Alpha', 'Beta', 'Gammalow', 'Fullband', 'Sleepband'};
            for bndidx = 1:length(allguibands)
                curband = allguibands{bndidx};
                eval(['app.' curband 'CheckBox.Value = 0;'])
                eval(['app.' curband 'CheckBox.Enable = ''' othersSetting ''';'])
                eval(['app.' curband 'LowLimFreq.Enable = ''off'';'])
                eval(['app.' curband 'HighLimFreq.Enable = ''off'';'])
                eval(['app.' curband 'N1.Enable = ''off'';'])
                eval(['app.' curband 'N2.Enable = ''off'';'])
                eval(['app.' curband 'N3.Enable = ''off'';'])
                eval(['app.' curband 'N4.Enable = ''off'';'])
                eval(['app.' curband 'R.Enable = ''off'';'])
                eval(['app.' curband 'W.Enable = ''off'';'])
                eval(['app.' curband 'Artifact.Enable = ''off'';'])
                if resetStages > 0
                    eval(['app.' curband 'N1.Value = 0;'])
                    eval(['app.' curband 'N2.Value = 0;'])
                    eval(['app.' curband 'N4.Value = 0;'])
                    eval(['app.' curband 'R.Value = 0;'])
                    eval(['app.' curband 'N3.Value = 0;'])
                    eval(['app.' curband 'W.Value = 0;'])
                end
                
            end
            
            %Enable requested bands
            if ~iscell(enableList) && strcmp(enableList, 'all')
                enableList = allguibands;
            end
            
            for bndidx = 1:length(enableList)
                curband = enableList{bndidx};
                if ~isempty(curband)
                    curband = [upper(curband(1)) lower(curband(2:end))];
                    if enableCheckbox == 1
                        eval(['app.' curband 'CheckBox.Value = 1;'])
                        eval(['app.' curband 'CheckBox.Enable = ''on'';'])
                    end
                    if enableFreqs == 1
                        eval(['app.' curband 'LowLimFreq.Enable = ''on'';'])
                        eval(['app.' curband 'HighLimFreq.Enable = ''on'';'])
                    end
                    if enableStages == 1
                        eval(['app.' curband 'N1.Enable = ''on'';'])
                        eval(['app.' curband 'N2.Enable = ''on'';'])
                        eval(['app.' curband 'N3.Enable = ''on'';'])
                        eval(['app.' curband 'N4.Enable = ''on'';'])
                        eval(['app.' curband 'R.Enable = ''on'';'])
                        eval(['app.' curband 'W.Enable = ''on'';'])
                        if resetStages == 1
                            eval(['app.' curband 'N1.Value = 1;'])
                            eval(['app.' curband 'N2.Value = 1;'])
                            eval(['app.' curband 'N3.Value = 1;'])
                            eval(['app.' curband 'N4.Value = 1;'])
                            eval(['app.' curband 'R.Value = 1;'])
                            eval(['app.' curband 'W.Value = 1;'])
                        end
                    end
                    if enableAR == 1
                        eval(['app.' curband 'Artifact.Enable = ''on'';'])
                    end
                end
            end
        end
        
        function app = bandCheckboxChanged(app, whichband, value)
            whichband = [upper(whichband(1)) lower(whichband(2:end))];
            
            if value == 1
                enablesetting = 'on';
                %valuesetting = num2str(1);
            else
                enablesetting = 'off';
                valuesetting = num2str(0);
                
                %if strcmp(resetstages, 'yes')
                eval(['app.' whichband 'N1.Value = ' valuesetting ';'])
                eval(['app.' whichband 'N2.Value = ' valuesetting ';'])
                eval(['app.' whichband 'N3.Value = ' valuesetting ';'])
                eval(['app.' whichband 'N4.Value = ' valuesetting ';'])
                eval(['app.' whichband 'R.Value = ' valuesetting ';'])
                eval(['app.' whichband 'W.Value = ' valuesetting ';'])
                %end
            end
            
            % Set default operation modes
            enablefreqsar = 'yes';
            enablestages = 'yes';
            %resetstages = 'no';
            
            % Account for Envelope Viewer (and other) GUI idiosyncrasies
            if strcmp(app.TabGroup.SelectedTab.Title, 'Envelope Viewer')
                if strcmp(app.AverageenvelopesacrossButtonGroup.SelectedObject.Text, 'chans')
                    enablefreqsar = 'no';
                    enablestages = 'no';
                    %resetstages = 'no';
                elseif strcmp(app.AverageenvelopesacrossButtonGroup.SelectedObject.Text, 'time')
                    enablefreqsar = 'no';
                    enablestages = 'yes';
                    %resetstages = 'no';
                end
                % Enable the Omit Empties setting if only one band is selected
                if ((app.SubdeltaCheckBox.Value + app.DeltaCheckBox.Value + app.ThetaCheckBox.Value + app.AlphaCheckBox.Value + app.SigmaCheckBox.Value + app.BetaCheckBox.Value + app.GammalowCheckBox.Value + app.FullbandCheckBox.Value + app.SleepbandCheckBox.Value)==1)
                    app.OmitEmptyEnvelopesCheckBox.Visible = 'on';
                else
                    app.OmitEmptyEnvelopesCheckBox.Visible = 'off';
                end
            elseif strcmp(app.TabGroup.SelectedTab.Title, 'Power (Hilbert)')
                if app.HilbertEnvelopesButtonGroup.SelectedObject == app.LoadexistingenvelopefilesButton
                    enablestages = 'no';
                end
            end
            
            % Update GUI settings
            if strcmp(enablefreqsar, 'yes')
                eval(['app.' whichband 'LowLimFreq.Enable = ''' enablesetting ''';'])
                eval(['app.' whichband 'HighLimFreq.Enable = ''' enablesetting ''';'])
                eval(['app.' whichband 'Artifact.Enable = ''' enablesetting ''';'])
            end
            if strcmp(enablestages, 'yes')
                eval(['app.' whichband 'N1.Enable = ''' enablesetting ''';'])
                eval(['app.' whichband 'N2.Enable = ''' enablesetting ''';'])
                eval(['app.' whichband 'N3.Enable = ''' enablesetting ''';'])
                eval(['app.' whichband 'N4.Enable = ''' enablesetting ''';'])
                eval(['app.' whichband 'R.Enable = ''' enablesetting ''';'])
                eval(['app.' whichband 'W.Enable = ''' enablesetting ''';'])
            end
            
            
            figure(app.UIFigure);
        end
        
        function app = ReportPSGpowerGUISettings(app, value)
            disp(' ');
            disp(['*****PSGpower version: ' app.PSGpowerVersionLabel.Text '*****']);
            disp(' ');
            disp('*****Step 1 settings*****');
            disp(['Chosen experiment: ' app.ExperimentnameDropDown.Value]);
            disp(['EEG folder: ' app.eegrootfolder]);
            disp(['Using subfolders: ' num2str(app.IncludesubdirectoriesCheckBox.Value)]);
            disp(['EEG file list: ' strjoin([{' '}, app.eegfiles], '\n     ')]);
            disp(['EEG sampling frequency (GUI): ' app.EEGSamplingFrequencyHzEditField.Value]);
            disp(['Sleep stage notation format: ' app.WhatsoftwarewasusedforsleepstagenotationDropDown.Value]);
            disp(['Sleep stage notation folder: ' app.stagefp]);
            disp(['Sleep stage notation files: ' strjoin([{' '}, app.stgfiles], '\n     ')]);
            disp(['Using montage key: ' app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Value]);
            disp(' ');
            
            disp('*****Step 2 settings*****');
            disp(['Chosen analysis method: ' app.AnalysisModuleselectoneDropDown.Value]);
            tabtoselect = regexprep([app.AnalysisModuleselectoneDropDown.Value 'Tab'], ' |\(|\)', '');
            eval(['curtab_children = app.' tabtoselect '.Children;']);
            disp('Analysis method settings: ');
            for childidx = 1:length(curtab_children)
                if strcmp(class(curtab_children(childidx)), 'matlab.ui.control.Label')
                    if iscell(curtab_children(childidx).Text)
                        disp(['     Label text: ' strjoin(curtab_children(childidx).Text, '\n     Label text: ')]);
                    else
                        disp(['     Label text: ' curtab_children(childidx).Text]);
                    end
                elseif isprop(curtab_children(childidx), 'Value')
                    if isprop(curtab_children(childidx), 'Text')
                        if iscell(curtab_children(childidx).Text)
                            disp(['     Component text: ' strjoin(curtab_children(childidx).Text, '\n     Component text: ')]);
                        else
                            disp(['     Component text: ' curtab_children(childidx).Text]);
                        end
                    end
                    if isnumeric(curtab_children(childidx).Value) || islogical(curtab_children(childidx).Value)
                        disp(['     Component value: ' num2str(curtab_children(childidx).Value)]);
                    else
                        disp(['     Component value: ' curtab_children(childidx).Value]);
                    end
                end
            end
            switch app.AnalysisModuleselectoneDropDown.Value
                case 'TMRtistry (WIP)'
                    disp(['TMRtistry settings: ' app.tmrtistry_settings]);
                    disp(['TMRtistry script: ' app.tmrtistry_script]);
            end
            disp(' ');
            
            disp('*****Step 3 settings*****');
            allguibands = {'Subdelta', 'Delta', 'Theta', 'Sigma', 'Alpha', 'Beta', 'Gammalow', 'Fullband', 'Sleepband'};
            for bndidx = 1:length(allguibands)
                curband = allguibands{bndidx};
                eval(['curbandon = num2str(app.' curband 'CheckBox.Value);'])
                eval(['curbandlowfreq = num2str(app.' curband 'LowLimFreq.Value);'])
                eval(['curbandhighfreq = num2str(app.' curband 'HighLimFreq.Value);'])
                eval(['curbandarthresh = num2str(app.' curband 'Artifact.Value);'])
                eval(['curbandn1on = num2str(app.' curband 'N1.Value);'])
                eval(['curbandn2on = num2str(app.' curband 'N2.Value);'])
                eval(['curbandn3on = num2str(app.' curband 'N3.Value);'])
                eval(['curbandn4on = num2str(app.' curband 'N4.Value);'])
                eval(['curbandRon = num2str(app.' curband 'R.Value);'])
                eval(['curbandWon = num2str(app.' curband 'W.Value);'])
                disp([curband ': On = ' curbandon '; Frequency range = ' curbandlowfreq '-' curbandhighfreq ' Hz; Artifact threshold = ' curbandarthresh ' uV; N1-N2-N3-N4-R-W on = ' curbandn1on '-' curbandn2on '-' curbandn3on '-' curbandn4on '-' curbandRon '-' curbandWon]);
            end
            disp(' ');
            
            disp('*****Step 4 settings*****');
            disp(['Referencing choice: ' app.RereferenceEEGDatatoButtonGroup.SelectedObject.Text]);
            if strcmp(app.RereferenceEEGDatatoButtonGroup.SelectedObject.Text, 'Custom')
                disp(['Custom rereferencing choice: ' app.CustomRerefEditField.Value]);
            end
            disp(['Force .set file recreation: ' num2str(app.ForcerecreationofEEGLABsetfilesCheckBox.Value)]);
            disp(['Run ICA decompositions: ' num2str(app.RunandsaveICAdecompositionsCheckBox.Value)]);
            disp(['Interpolate (manually identified) bad channels: ' num2str(app.InterpolatemanuallyidentifiedbadchannelsCheckBox.Value)]);
            if strcmp(app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Enable, 'on')
                disp(['Interpolate extra theta bad channels (MRI-SRT only): ' num2str(app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Value)]);
            end
            disp(['Downsample data choice: ' num2str(app.DownsampletoHzCheckBox.Value)]);
            disp(['Downsample data value (only applied if choice == 1): ' num2str(app.DownsampledFrequencyNumericEditField.Value)]);
            disp(['ARBCI settings: ' app.arbci_settings]);
            disp(['Preprocessing settings: ' app.preproc_settings]);
            disp(' ');
            
            disp(['*****Analyze procedure started at ' datestr(now) '*****']);
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Initialize GUI state
            app = refreshGUI(app);
            app = setARthreshDefaults(app);
            
            %%%Check for recommended dependency versions
            
            % Check whether repo is up to date (very experimental)
            % PSGpower_checkforupdates();
            
            %EEGLAB
            receeglabver = '13_6_5b';
            if exist('eeglab') ~= 2
                curerrorstr = ['EEGLAB is not found on your MATLAB path. This program requires EEGLAB v' receeglabver ' to run, so it will exit now. Add EEGLAB v' receeglabver ' to your MATLAB path (download EEGLAB from https://sccn.ucsd.edu/eeglab/ if needed), then relaunch PSGpower.'];
                msgbox(curerrorstr, 'EEGLAB not found');
                error(['ERROR: ' curerrorstr ' Exiting...']);
                closereq;
            elseif isempty(regexp(which('eeglab'), receeglabver))
                qeeglabwrong = questdlg(['WARNING: EEGLAB was found on your path, but it is not the recommended version (v' receeglabver '). Do you wish to continue at your own risk? Or exit?'], 'Incompatible EEGLAB', 'Continue', 'Exit', 'Exit');
                if strcmp(qeeglabwrong, 'Exit')
                    error(['ERROR: Incompatible EEGLAB found. Download EEGLAB v' receeglabver ' from https://sccn.ucsd.edu/eeglab/, extract it, and add it to the MATLAB path after removing other EEGLAB versions from the path. Exiting...']);
                    closereq;
                end
            end
            eeglab; %Init EEGLAB
            figure(app.UIFigure); % Refocus GUI as main window
            
            %ERPLAB
            recerplabver = '6.1.4';
            if exist('erplab') ~= 2
                msgbox('ERPLAB is not found on your MATLAB path. This script requires ERPLAB to run, so it will exit now. If you need to download ERPLAB it can be found at https://erpinfo.org/erplab/', 'ERPLAB not found');
                error('ERROR: ERPLAB not found. Add it to the MATLAB path or download it from https://erpinfo.org/erplab/. Exiting...');
                closereq;
            elseif isempty(regexp(which('erplab'), recerplabver))
                qerplabwrong = questdlg(['WARNING: ERPLAB was found on your path, but it is not the recommended version (v' recerplabver '). Do you wish to continue at your own risk? Or exit?'], 'Incompatible ERPLAB', 'Continue', 'Exit', 'Exit');
                if strcmp(qeeglabwrong, 'Exit')
                    error(['ERROR: Incompatible ERPLAB found. Download ERPLAB v' recerplabver ' from https://erpinfo.org/erplab/ and extract it to the EEGLAB plugins directory after removing other ERPLAB versions from the plugins directory. Exiting...']);
                    closereq;
                end
            end
            
            %%%Initialize global variables
            global isanalyze;
            global logdir;
            isanalyze = 0;
            logdir = '';
        end

        % Value changed function: ExperimentnameDropDown
        function ExperimentnameDropDownValueChanged(app, event)
            value = app.ExperimentnameDropDown.Value;
            
            %Refresh GUI state
            app = refreshGUI(app);
            app = setARthreshDefaults(app);
            
            switch value
                case 'Custom'
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(0);
                case 'EMO-MRI'
                    app.IncludesubdirectoriesCheckBox.Enable = 'on';
                    app.IncludesubdirectoriesCheckBox.Value = 1;
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(5000);
                case 'Emo_Storybook'
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(500);
                case 'EmoPreschool'
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(200);                
                case 'ITNS'
                    app.IncludesubdirectoriesCheckBox.Enable = 'on';
                    app.IncludesubdirectoriesCheckBox.Value = 0;
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(500);
                case 'Maryland'
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(500);
                case 'Mirror'
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(500);
                case 'MRI-SRT'
                    app.IncludesubdirectoriesCheckBox.Enable = 'on';
                    app.IncludesubdirectoriesCheckBox.Value = 1;
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(500);
                    app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Visible = 'on';
                case 'SRTOT'
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(500);                
                case 'Storybook'
                    app.IncludesubdirectoriesCheckBox.Enable = 'on';
                    app.IncludesubdirectoriesCheckBox.Value = 1;
                    app.EEGSamplingFrequencyHzEditField.Value = num2str(500);
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: AddDirectoryforAnalysisButton
        function AddDirectoryforAnalysisButtonPushed(app, event)
            
            dlgstr = 'Select the directory containing all .edf or .vhdr files you want to process:';
            
            [app.eegrootfolder] = uigetdir('*.*', dlgstr);
            clear dlgstr;
            
            if app.eegrootfolder == 0
                % user selected cancel, don't do anything
            elseif length(app.eegrootfolder) > 0
                
                app.eegrootfolder = [strrep(app.eegrootfolder, '\', '/'), '/'];
                cd(app.eegrootfolder);
                
                if ~exist('app.eegfiles') || ~exist('app.stgfiles')
                    app.subdirlist = dir(app.eegrootfolder);
                    app.subdirlist = {app.subdirlist([app.subdirlist.isdir] == 1).name};
                    
                    app.subdirlist = app.subdirlist(~strcmp(app.subdirlist, '.'));
                    app.subdirlist = app.subdirlist(~strcmp(app.subdirlist, '..'));
                    
                    if length(app.subdirlist) > 0
                        subsearch = app.IncludesubdirectoriesCheckBox.Value;
                        if subsearch == 1
                            if any(regexp(app.eegrootfolder, 'MRI-SRT|EMO-MRI'))
                                whichdirs = listdlg('ListString', app.subdirlist, 'SelectionMode', 'multiple', 'Name', 'Dirs?', 'PromptString', 'Which folders to search for EEG files?');
                                app.subdirlist = app.subdirlist(whichdirs);
                                app.eegfiles = [];
                                for d = 1:length(app.subdirlist)
                                    app.cursubdir = [app.eegrootfolder app.subdirlist{d} '/psg/'];
                                    app.vhdrfiles = dir([app.cursubdir '*.vhdr']);
                                    app.eegfiles = [app.eegfiles strcat(app.cursubdir, {app.vhdrfiles.name})];
                                end
                                app.eegfiles = app.eegfiles(cellfun(@isempty, regexp(app.eegfiles, 'endimp')));
                                
                                app.stagefp = [app.eegrootfolder 'Analysis/Sleep_Scoring/'];
                                waitfor(msgbox(['Pulling stage notation files from ' app.stagefp ' (so make sure the notation files you want to use are loaded there)...']));
                                app.stgfiles = dir([app.stagefp '*.mat']);
                                app.stgfiles = {app.stgfiles.name};
                                app.stgfiles = strcat(app.stagefp, app.stgfiles(cellfun(@isempty, regexp(app.stgfiles, 'stageStats'))));
                                
                                scaneegrootonly = 0;
                            elseif any(regexp(app.eegrootfolder, 'Storybook|ITNS'))
                                whichdirs = listdlg('ListString', app.subdirlist, 'SelectionMode', 'multiple', 'Name', 'Dirs?', 'PromptString', 'Which folders to search for EEG files?');
                                app.subdirlist = app.subdirlist(whichdirs);
                                app.eegfiles = [];
                                app.stgfiles = [];
                                for d = 1:length(app.subdirlist)
                                    app.cursubdir = [app.eegrootfolder app.subdirlist{d} '/'];
                                    app.vhdrfiles = dir([app.cursubdir '*.vhdr']);
                                    app.txtfiles = dir([app.cursubdir '*.txt']);
                                    app.matfiles = dir([app.cursubdir '*.mat']);
                                    app.eegfiles = [app.eegfiles strcat(app.cursubdir, {app.vhdrfiles.name})];
                                    app.stgfiles = [app.stgfiles strcat(app.cursubdir, unique([{app.txtfiles.name} {app.matfiles.name}]))];
                                end
                                scaneegrootonly = 0;
                            else
                                msgbox('This program cannot handle subdirectory structures for experiments other than {MRI-SRT, EMO-MRI, Storybook, ITNS} right now- see Ahren to implement this for your experiment, or move all of your EEG and stage files to a single directory. This program will now continue as if your data directory had no subdirectories.');
                                scaneegrootonly = 1;
                                %Started to write a flexible version, realized it would be crazy to
                                %account for all edge cases. Leaving this here in case it is
                                %desired to be finished in the future - ABF 2019-03-27
                                
                                %             for d = 1:length(app.subdirlist)
                                %                 subsubdirlist = dir([eegfp '/' app.subdirlist{1} '/*.']);
                                %                 subsubdirlist = {subsubdirlist.name};
                                %                 if d == 1 && length(subsubdirlist) > 2
                                %                     msgbox('Your subdirectories have subdirectories! Navigate to the proper sub-sub-etc directory for searching (this assumes your directory substructure is consistent across PID folders).', 'Recursive madness');
                                %                     [subsubsubdir] = uigetdir([eegfp '/' dirlist{1}], 'Choose sub-sub-etc directory that actually contains data');
                                %                 end
                                %             end
                            end
                        else %if subsearch ~= 1
                            scaneegrootonly = 1;
                        end
                    else %if no subdirectories exist
                        scaneegrootonly = 1;
                    end
                    
                    if scaneegrootonly == 1
                        app.edffiles = dir([app.eegrootfolder '*.edf']);
                        app.EDFfiles = dir([app.eegrootfolder '*.EDF']);
                        app.vhdrfiles = dir([app.eegrootfolder '*.vhdr']);
                        app.eegfiles = unique([{app.edffiles.name} {app.EDFfiles.name} {app.vhdrfiles.name}]);
                        
                        app.txtfiles = dir([app.eegrootfolder '*.txt']);
                        app.matfiles = dir([app.eegrootfolder '*.mat']);
                        app.stgfiles = unique([{app.txtfiles.name} {app.matfiles.name}]);
                    end
                end
                
                % Update GUI files list based on user selections
                app.FileSelectListbox.Items = app.eegfiles;
                
                if length(app.FileSelectListbox.Items) > 0
                    app.DirectoryConfirmationLabel.Visible = 'on';
                    app.WhatsoftwarewasusedforsleepstagenotationDropDownLabel.Visible = 'on';
                    app.WhatsoftwarewasusedforsleepstagenotationDropDown.Visible = 'on';
                end
            end
            
            % GUI Modifications based on montage key presence
            if exist([app.eegrootfolder 'PID_Condition_Montage_Key.csv'])
                app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Visible = 'on';
                app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Value = 'Yes';
                app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitchLabel.Visible = 'on';
            end
            
            figure(app.UIFigure);
        end

        % Value changed function: 
        % WhatsoftwarewasusedforsleepstagenotationDropDown
        function WhatsoftwarewasusedforsleepstagenotationDropDownValueChanged(app, event)
            value = app.WhatsoftwarewasusedforsleepstagenotationDropDown.Value;
            
            if strcmp(value, ' ')
                app.EEGSamplingFrequencyHzEditField.Visible = 'off';
                app.EEGSamplingFrequencyHzEditFieldLabel.Visible = 'off';
                app.ChooseSubsetButton.Visible = 'off';
                app.Step2SelectsignalprocessingapproachPanel.Visible = 'off';
                app.Step3SelectbandsandstagesPanel.Visible = 'off';
                app.Step4OthersettingsPanel.Visible = 'off';
                app.AnalyzeButton.Enable = 'off';
            else
                app.EEGSamplingFrequencyHzEditField.Visible = 'on';
                app.EEGSamplingFrequencyHzEditFieldLabel.Visible = 'on';
                
                EEGSamplingFrequencyHzEditFieldValueChanged(app);
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Value changed function: EEGSamplingFrequencyHzEditField
        function EEGSamplingFrequencyHzEditFieldValueChanged(app, event)
            value = str2num(app.EEGSamplingFrequencyHzEditField.Value);
            
            if value == 0
                app.ChooseSubsetButton.Visible = 'off';
                app.FileSelectListbox.Visible = 'off';
                app.Step2SelectsignalprocessingapproachPanel.Visible = 'off';
                app.Step3SelectbandsandstagesPanel.Visible = 'off';
                app.Step4OthersettingsPanel.Visible = 'off';
                app.AnalyzeButton.Enable = 'off';
            else
                app.ChooseSubsetButton.Visible = 'on';
                app.FileSelectListbox.Visible = 'on';
                if length(app.FileSelectListbox.Items) > 0
                    app.Step2SelectsignalprocessingapproachPanel.Visible = 'on';
                end
                
                if (value > 500)% && (app.ForcerecreationofEEGLABsetfilesCheckBox.Value == 1)
                    app.SampRateisHighLabel.Visible = 'on';
                    app.DownsampletoHzCheckBox.Value = 1;
                    app.DownsampletoHzCheckBox.Visible = 'on';
                    app.DownsampledFrequencyNumericEditField.Visible = 'on';
                    app.DownsampledFrequencyNumericEditField.Value = 500;
                else
                    app.SampRateisHighLabel.Visible = 'off';
                    app.DownsampletoHzCheckBox.Value = 0;
                    app.DownsampletoHzCheckBox.Visible = 'off';
                    app.DownsampledFrequencyNumericEditField.Visible = 'off';
                end
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Value changed function: ChooseSubsetButton
        function ChooseSubsetButtonValueChanged(app, event)
            value = app.ChooseSubsetButton.Value;
            
            if value == 1
                app.SelectfilestobeanalyzedwhileholdingCtrlthenclickLabel.Visible = 'on';
                app.FileSelectListbox.Enable = 'on';
                app.UpdateFileListButton.Visible = 'on';
                app.Step2SelectsignalprocessingapproachPanel.Visible = 'off';
            else
                app.SelectfilestobeanalyzedwhileholdingCtrlthenclickLabel.Visible = 'off';
                app.FileSelectListbox.Enable = 'off';
                app.UpdateFileListButton.Visible = 'off';
                app.Step2SelectsignalprocessingapproachPanel.Visible = 'on';
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: UpdateFileListButton
        function UpdateFileListButtonPushed(app, event)
            [~,idx] = ismember(app.FileSelectListbox.Value,app.FileSelectListbox.Items);
            
            app.FileSelectListbox.Items = app.FileSelectListbox.Items(idx);
            app.eegfiles = app.FileSelectListbox.Items;
            
            % Fix the GUI components that were disabled to make the user
            % update their file list
            if length(app.FileSelectListbox.Items) > 0
                app.DirectoryConfirmationLabel.Visible = 'on';
                app.Step2SelectsignalprocessingapproachPanel.Visible = 'on';
                
            else
                app.DirectoryConfirmationLabel.Visible = 'off';
                app.Step2SelectsignalprocessingapproachPanel.Visible = 'off';
                
            end
            
            app.ChooseSubsetButton.Value = 0;
            app.SelectfilestobeanalyzedwhileholdingCtrlthenclickLabel.Visible = 'off';
            app.FileSelectListbox.Enable = 'off';
            app.UpdateFileListButton.Visible = 'off';
            
            figure(app.UIFigure); % Refocus GUI as main window
            
        end

        % Value changed function: AnalysisModuleselectoneDropDown
        function AnalysisModuleselectoneDropDownValueChanged(app, event)
            value = app.AnalysisModuleselectoneDropDown.Value;
            
            if strcmp(value, ' ')
                app.TabGroup.Visible = 'off';
                app.Step3SelectbandsandstagesPanel.Visible = 'off';
                app.Step4OthersettingsPanel.Visible = 'off';
                app.AnalyzeButton.Enable = 'off';
            else
                app.TabGroup.Visible = 'on';
                app.Step3SelectbandsandstagesPanel.Visible = 'on';
                app.Step4OthersettingsPanel.Visible = 'on';
                app.AnalyzeButton.Enable = 'on';
                
                app = moduleGUIsetup(app, value);
                
            end
            
            figure(app.UIFigure);
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            app = moduleGUIsetup(app, app.TabGroup.SelectedTab.Title);
            
            figure(app.UIFigure);
            
        end

        % Callback function: Step3SelectbandsandstagesPanel, 
        % SubdeltaCheckBox
        function SubdeltaCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'subdelta', app.SubdeltaCheckBox.Value);
            
        end

        % Value changed function: DeltaCheckBox
        function DeltaCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'delta', app.DeltaCheckBox.Value);
            
        end

        % Value changed function: ThetaCheckBox
        function ThetaCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'theta', app.ThetaCheckBox.Value);
            
            %For Power (Hilbert)
            if app.ThetaCheckBox.Value == 1 && app.HilbertEnvelopesButtonGroup.SelectedObject == app.CreatenewenvelopefilesButton
                app.ThetaFilterModeDropDownLabel.Visible = 'on';
                app.ThetaFilterModeDropDown.Visible = 'on';
                app.ThetaFilterModeDropDown.Value = 'cheby2';
                
            else
                app.ThetaFilterModeDropDownLabel.Visible = 'off';
                app.ThetaFilterModeDropDown.Visible = 'off';
                app.ThetaFilterModeDropDown.Value = 'none';
                
            end
            ThetaFilterModeDropDownValueChanged(app);
            
            %For Envelope Viewer
            if strcmp(app.CollateorloadenvelopesButtonGroup.SelectedObject.Text, 'Collate') && app.ThetaCheckBox.Value == 1
                app.ThetaFilterModeDropDown_EnvViewer.Visible = 'on';
                app.ThetaFilterModeDropDown_EnvViewerLabel.Visible = 'on';
                
            else
                app.ThetaFilterModeDropDown_EnvViewer.Visible = 'off';
                app.ThetaFilterModeDropDown_EnvViewer.Value = 'none';
                app.ThetaFilterModeDropDown_EnvViewerLabel.Visible = 'off';
                
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Value changed function: AlphaCheckBox
        function AlphaCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'alpha', app.AlphaCheckBox.Value);
            
        end

        % Value changed function: SigmaArtifact, SigmaCheckBox
        function SigmaCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'sigma', app.SigmaCheckBox.Value);
            
        end

        % Value changed function: BetaCheckBox
        function BetaCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'beta', app.BetaCheckBox.Value);
            
        end

        % Value changed function: GammalowCheckBox
        function GammalowCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'gammalow', app.GammalowCheckBox.Value);
            
        end

        % Value changed function: FullbandCheckBox
        function FullbandCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'fullband', app.FullbandCheckBox.Value);
            
        end

        % Value changed function: SleepbandCheckBox
        function SleepbandCheckBoxValueChanged(app, event)
            
            app = bandCheckboxChanged(app, 'sleepband', app.SleepbandCheckBox.Value);
            
        end

        % Value changed function: CustomRerefEditField, 
        % ForcerecreationofEEGLABsetfilesCheckBox
        function ForcerecreationofEEGLABsetfilesCheckBoxValueChanged(app, event)
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Selection changed function: RereferenceEEGDatatoButtonGroup
        function RereferenceEEGDatatoButtonGroupSelectionChanged(app, event)
            selectedButton = app.RereferenceEEGDatatoButtonGroup.SelectedObject;
            
            if selectedButton == app.CustomRerefButton
                app.CustomRerefEditField.Enable = 'on';
            else
                app.CustomRerefEditField.Enable = 'off';
            end
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: AllBandsButton
        function AllBandsButtonPushed(app, event)
            app.SubdeltaCheckBox.Value = 1;
            app.DeltaCheckBox.Value = 1;
            app.ThetaCheckBox.Value = 1;
            app.AlphaCheckBox.Value = 1;
            app.SigmaCheckBox.Value = 1;
            app.BetaCheckBox.Value = 1;
            app.GammalowCheckBox.Value = 1;
            app.FullbandCheckBox.Value = 1;
            app.SleepbandCheckBox.Value = 1;
            
            app = bandCheckboxChanged(app, 'subdelta', app.SubdeltaCheckBox.Value);
            app = bandCheckboxChanged(app, 'delta', app.DeltaCheckBox.Value);
            app = bandCheckboxChanged(app, 'theta', app.ThetaCheckBox.Value);
            app = bandCheckboxChanged(app, 'alpha', app.AlphaCheckBox.Value);
            app = bandCheckboxChanged(app, 'sigma', app.SigmaCheckBox.Value);
            app = bandCheckboxChanged(app, 'beta', app.BetaCheckBox.Value);
            app = bandCheckboxChanged(app, 'gammalow', app.GammalowCheckBox.Value);
            app = bandCheckboxChanged(app, 'fullband', app.FullbandCheckBox.Value);
            app = bandCheckboxChanged(app, 'sleepband', app.SleepbandCheckBox.Value);
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: AllStagesButton
        function AllStagesButtonPushed(app, event)
            allguibands = {'Subdelta', 'Delta', 'Theta', 'Sigma', 'Alpha', 'Beta', 'Gammalow', 'Fullband', 'Sleepband'};
            allguistages = {'N1', 'N2', 'N3', 'N4', 'R', 'W'};
            
            for bndidx = 1:length(allguibands)
                curband = allguibands{bndidx};
                curbandison = eval(['app.' curband 'CheckBox.Value == 1;']);
                
                if curbandison == 1
                    for stgidx = 1:length(allguistages)
                        curstage = allguistages{stgidx};
                        curstageison = eval(['app.' curband curstage '.Enable;']);
                        
                        if strcmp(curstageison, 'on')
                            eval(['app.' curband curstage '.Value = 1;'])
                        end
                    end
                end
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Value changed function: 
        % InterpolatemanuallyidentifiedbadchannelsCheckBox
        function InterpolatemanuallyidentifiedbadchannelsCheckBoxValueChanged(app, event)
            value = app.InterpolatemanuallyidentifiedbadchannelsCheckBox.Value;
            
            if value == 1 && strcmp(app.ExperimentnameDropDown.Value, 'MRI-SRT')
                app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Value = 1;
                app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Visible = 'on';
            else
                app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Value = 0;
                app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Visible = 'off';
            end
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Value changed function: ThetaFilterModeDropDown
        function ThetaFilterModeDropDownValueChanged(app, event)
            value = app.ThetaFilterModeDropDown.Value;
            
            if strcmp(value, 'FIR')
                app.ThetaArtifact.Value = 250;
            elseif strcmp(value, 'cheby1')
                app.ThetaArtifact.Value = 75;
            elseif strcmp(value, 'cheby2')
                app.ThetaArtifact.Value = 75;
            else
                app.ThetaArtifact.Value = 150;
            end
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Selection changed function: HilbertEnvelopesButtonGroup
        function HilbertEnvelopesButtonGroupSelectionChanged(app, event)
            selectedButton = app.HilbertEnvelopesButtonGroup.SelectedObject;
            
            if selectedButton == app.CreatenewenvelopefilesButton
                app = setStep3Bands(app, {'delta', 'theta', 'sigma'}, 'othersOn', 1, 1, 1, 1, 1, 1);
                app.FiniteImpulseResponseFIRorderButtonGroup.Visible = 'on';
                app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDownLabel.Visible = 'on';
                app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown.Visible = 'on';
                ThetaCheckBoxValueChanged(app);
                
            elseif selectedButton == app.LoadexistingenvelopefilesButton
                app = setStep3Bands(app, {'delta', 'theta', 'sigma'}, 'othersOn', 1, 1, 1, 0, 0, 0);
                app.FiniteImpulseResponseFIRorderButtonGroup.Visible = 'off';
                app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDownLabel.Visible = 'off';
                app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown.Visible = 'off';
                app.ThetaFilterModeDropDown.Visible = 'off';
                ThetaCheckBoxValueChanged(app);
                
            end
            
            ThetaFilterModeDropDownValueChanged(app);
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: ImportPSGpowerParametersButton
        function ImportPSGpowerParameterSettingsMenuSelected(app, event)
            if ismac == 0
                [filename, path] = uigetfile('title', 'Select file containing exported PSGpower settings','PSGpower Parameter Export');
            else
                [filename, path] = uigetfile('PSGpower Parameter Export.*', 'Select file containing exported PSGpower settings','PSGpower Parameter Export');
            end
            
            if  filename ~= 0
                load(strcat(path, filename));
                
                if exist('psgpowersettings')
                    %Maybe add a legacy load option if no version string is detected?
                    %If so, call an external script containing RTM's original solution
                elseif exist('PSGpower_settings')
                    if isfield(PSGpower_settings, 'CreatedByVersion')
                        %Check version string here, report error dialog if mismatch. Allow attempted loading anyway if desired.
                        if strcmp(PSGpower_settings.CreatedByVersion, app.PSGpowerVersionLabel.Text)
                            disp(['Current version of PSGpower (' app.PSGpowerVersionLabel.Text ') is the same as that used to export parameters (' PSGpower_settings.CreatedByVersion '), importing previously saved GUI settings...']);
                        else
                            mismatchchoice = questdlg(['The version of PSGpower used to export the chosen parameters (' PSGpower_settings.CreatedByVersion ') does not match the version of PSGpower currently in use (' app.PSGpowerVersionLabel.Text '). Attempting to import these GUI parameters could cause errors. Would you like to try anyway? If so, make sure to check your GUI carefully afterwards!'], 'PSGpower Version mismatch');
                            if ~strcmp(mismatchchoice, 'Yes')
                                return
                            end
                        end
                    end
                    
                    setprops = fields(PSGpower_settings);
                    appprops = properties(app);
                    
                    for p = 2:length(setprops) %Hack to skip CreatedByVersion. Should make this explicit.
                        eval(['curprop = PSGpower_settings.' setprops{p} ';']);
                        if isstruct(curprop)
                            curpropprops = fields(curprop);
                            try
                                if any(strcmp(curpropprops, 'Enable'))
                                    eval(['app.' setprops{p} '.Enable = curprop.Enable;']);
                                end
                                if any(strcmp(curpropprops, 'Visible'))
                                    eval(['app.' setprops{p} '.Visible = curprop.Visible;']);
                                end
                                if ~isempty(strfind(setprops{p}, 'Listbox'))
                                    if any(strcmp(curpropprops, 'Items'))
                                        eval(['app.' setprops{p} '.Items = curprop.Items{1};']);
                                    end
                                end
                                if ~isempty(strfind(setprops{p}, 'TabGroup'))
                                    if any(strcmp(curpropprops, 'SelectedTab'))
                                        tabtoselect = regexprep([curprop.SelectedTab.Title 'Tab'], ' |\(|\)', '');
                                        eval(['app.' setprops{p} '.SelectedTab = app.' tabtoselect ';']);
                                    end
                                end
                                if ~isempty(strfind(setprops{p}, 'ButtonGroup'))
                                    eval(['curappprop = app.' setprops{p} ';']);
                                    if all(strcmp({curappprop.Buttons.Text}, curprop.Buttons.Text))
                                        for rad = 1:length(curprop.Buttons.Value)
                                            eval(['app.' setprops{p} '.Buttons(rad).Value = curprop.Buttons.Value(rad);']);
                                        end
                                    else
                                        uiwait(msgbox(['WARNING! The ' setprops{p} ' radio button choices in the imported parameters file do not match those in the current GUI. This setting will not be loaded, please check it and set it manually.'], 'Parameter mismatch'));
                                    end
                                end
                                if any(strcmp(curpropprops, 'Value'))
                                    eval(['app.' setprops{p} '.Value = curprop.Value;']);
                                end
                            catch
                                disp(['ERROR: Could not import setting ' setprops{p} '. Moving on to next saved setting...']);
                            end
                        else
                            eval(['app.' setprops{p} ' = curprop;']);
                        end
                    end
                end
                
                cd(app.eegrootfolder);
                
                %Update lamp colors. Could instead add saving 'Color' subproperty to the export function. - ABF 2022-04-06
                if ~isempty(app.arbci_settings)
                    app.ARBCISettingsLamp.Color = 'g';
                else
                    app.ARBCISettingsLamp.Color = 'r';
                end
                if ~isempty(app.preproc_settings)
                    app.PreProcessingSettingsLamp.Color = 'g';
                else
                    app.PreProcessingSettingsLamp.Color = 'r';
                end
                if ~isempty(app.tmrtistry_settings)
                    app.TMRtistrySettingsLamp.Color = 'g';
                else
                    app.TMRtistrySettingsLamp.Color = 'r';
                end
                if ~isempty(app.tmrtistry_script)
                    app.TMRtistryCustomScriptLamp.Color = 'g';
                else
                    app.TMRtistryCustomScriptLamp.Color = 'r';
                end
                
                disp('GUI parameter import complete!');
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: ExportPSGpowerParametersButton
        function ExportPSGpowerParameterSettingsMenuSelected(app, event)
            global isanalyze;
            global logdir;
            
            if isanalyze == 0
                path = uigetdir('title', 'Select where to save the current PSGpower settings');
            else
                path = logdir;
            end
            
            if path ~= 0
                props = properties(app);
                PSGpower_settings = [];
                PSGpower_settings.CreatedByVersion = app.PSGpowerVersionLabel.Text;
                
                for p = 1:length(props)
                    eval(['curprop = app.' props{p} ';']);
                    curpropprops = properties(curprop);
                    
                    if any(strcmp(curpropprops, 'Value'))
                        eval(['PSGpower_settings.' props{p} '.Value = curprop.Value;']);
                    end
                    if any(strcmp(curpropprops, 'Enable'))
                        eval(['PSGpower_settings.' props{p} '.Enable = curprop.Enable;']);
                    end
                    if any(strcmp(curpropprops, 'Visible'))
                        eval(['PSGpower_settings.' props{p} '.Visible = curprop.Visible;']);
                    end
                    if ~isempty(strfind(props{p}, 'Listbox'))
                        if any(strcmp(curpropprops, 'Items'))
                            eval(['PSGpower_settings.' props{p} '.Items = {curprop.Items};']);
                        end
                    end
                    if ~isempty(strfind(props{p}, 'TabGroup'))
                        if any(strcmp(curpropprops, 'SelectedTab'))
                            eval(['PSGpower_settings.' props{p} '.SelectedTab = curprop.SelectedTab;']);
                        end
                    end
                    if ~isempty(strfind(props{p}, 'ButtonGroup'))
                        eval(['PSGpower_settings.' props{p} '.Buttons.Value = [curprop.Buttons.Value];']);
                        eval(['PSGpower_settings.' props{p} '.Buttons.Text = {curprop.Buttons.Text};']);
                        eval(['PSGpower_settings.' props{p} '.SelectedObject.Value = [curprop.SelectedObject.Value];']);
                        eval(['PSGpower_settings.' props{p} '.SelectedObject.Text = {curprop.SelectedObject.Text};']);
                    end
                    if isempty(curpropprops)
                        eval(['PSGpower_settings.' props{p} ' = curprop;']);
                    end
                end
                
                if isanalyze == 0
                    save(strcat(path, '\PSGpower Parameter Export'), 'PSGpower_settings');
                else
                    save(strcat(path, '\PSGpower Parameter Export_lastrun'), 'PSGpower_settings');
                end
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.AnalyzeButton.FontSize = 12;
            app.AnalyzeButton.Text = 'Analyzing (see command window output for status)....';
            
            % Start the diary log
            global logdir;
            logdir = [app.eegrootfolder 'EEG_GroupOutput/logs/'];
            if exist(logdir,'dir') ~= 7
                mkdir(logdir);
            end
            
            if strcmp(app.AnalysisModuleselectoneDropDown.Value, 'Envelope Viewer')
                diary([logdir 'EnvViewer_log_' datestr(now, 'yyyy-mm-dd_HH-MM-SS') '.txt']);
            else
                diary([logdir 'PSGpower_log_' datestr(now, 'yyyy-mm-dd_HH-MM-SS') '.txt']);
            end
            
            try
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% PSGPOWER SOFTWARE HOUSEKEEPING
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                global isanalyze;
                isanalyze = 1;
                ExportPSGpowerParameterSettingsMenuSelected(app);
                
                % Output GUI settings to command window (and log) for record - ABF 2022-04-11
                ReportPSGpowerGUISettings(app);
                
                figure(app.UIFigure); % Refocus GUI as main window
                
                % Get dipfit version
                if exist(strrep(which('eeglab'), 'eeglab.m', 'plugins/dipfit2.3'))
                    dipfitstr = 'dipfit2.3';
                elseif exist(strrep(which('eeglab'), 'eeglab.m', 'plugins/dipfit3.6'))
                    dipfitstr = 'dipfit3.6';
                end
                
                % Find PSGpower folders
                psgpowfp = mfilename('fullpath');
                psgpowfp = regexprep(psgpowfp, [mfilename '$'], '');
                modfp = [psgpowfp '/modules/'];
                extfp = [psgpowfp '/externals/'];
                if exist('pow_gettags') ~= 2
                    addpath([modfp]);
                end
                
                % Load MATLAB File Exchange functions
                if exist('distinguishable_colors') ~= 2
                    %Tim Holy (2022). Generate maximally perceptually-distinct colors
                    %(https://www.mathworks.com/matlabcentral/fileexchange/29702-generate-maximally-perceptually-distinct-colors),
                    %MATLAB Central File Exchange.
                    addpath([extfp 'distinguishable_colors']);
                end
                if exist('imwrite2tif') ~= 2
                    %Zhang Jiang (2022). Export image to TIF or TIFF file of selected data type
                    %(https://www.mathworks.com/matlabcentral/fileexchange/30519-export-image-to-tif-or-tiff-file-of-selected-data-type),
                    %MATLAB Central File Exchange.
                    addpath([extfp 'imwrite2tif']);
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Translate GUI parameters into legacy/user-friendly variable names
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %%%STEP 1
                chooseexp = app.ExperimentnameDropDown.Value; %Could rename this to expname to simplify briefcase packing later - ABF 2022-04-27 %Note: 'chooseexp' is called in Preproc_Settings.txt, and so should be left with that name. - ABF 2022-05-23
                subsearch = app.IncludesubdirectoriesCheckBox.Value;
                usemontagekey = app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Value;
                if strcmp(usemontagekey, 'Yes')
                    disp(['Loading ' app.eegrootfolder 'PID_Condition_Montage_Key.csv...']);
                    [~,~,montagekey] = xlsread([app.eegrootfolder 'PID_Condition_Montage_Key.csv']);
                end
                
                stgmethod = app.WhatsoftwarewasusedforsleepstagenotationDropDown.Value;
                switch stgmethod
                    case 'SleepSMG/Hume'
                        stgmethod = 'sleepsmg';
                    case 'TWin'
                        stgmethod = 'twin';
                    case 'REMlogic'
                        stgmethod = 'remlogic';
                    case 'None (Experimental)'
                        stgmethod = '';
                end
                
                thinkfs = str2num(app.EEGSamplingFrequencyHzEditField.Value);
                
                %%%STEP 3 (STEP 2 is below, after STEP 4)
                % Determine which bands have been selected
                bands = {'subdelta', 'delta', 'theta', 'alpha', 'sigma', 'beta', 'lowgamma', 'fullband', 'sleepband'};
                userselected_bands = logical([app.SubdeltaCheckBox.Value, ...
                    app.DeltaCheckBox.Value, ...
                    app.ThetaCheckBox.Value, ...
                    app.AlphaCheckBox.Value, ...
                    app.SigmaCheckBox.Value, ...
                    app.BetaCheckBox.Value, ...
                    app.GammalowCheckBox.Value, ...
                    app.FullbandCheckBox.Value, ...
                    app.SleepbandCheckBox.Value]);
                bands(~userselected_bands) = [];
                
                % Use userselected bands to determine bandfreqs
                bandfreqs = {[app.SubdeltaLowLimFreq.Value app.SubdeltaHighLimFreq.Value],...
                    [app.DeltaLowLimFreq.Value app.DeltaHighLimFreq.Value],...
                    [app.ThetaLowLimFreq.Value app.ThetaHighLimFreq.Value],...
                    [app.AlphaLowLimFreq.Value app.AlphaHighLimFreq.Value],...
                    [app.SigmaLowLimFreq.Value app.SigmaHighLimFreq.Value],...
                    [app.BetaLowLimFreq.Value app.BetaHighLimFreq.Value],...
                    [app.GammalowLowLimFreq.Value app.GammalowHighLimFreq.Value],...
                    [app.FullbandLowLimFreq.Value app.FullbandHighLimFreq.Value],...
                    [app.SleepbandLowLimFreq.Value app.SleepbandHighLimFreq.Value]};
                bandfreqs(~userselected_bands) = [];
                fullbw = [app.FullbandLowLimFreq.Value app.FullbandHighLimFreq.Value];
                
                % Use userselected bands to determine bandstgs
                knowntags = {'Stage - No Stage', 'Stage - W', 'Stage - R', 'Stage - N1', 'Stage - N2', 'Stage - N3', 'Stage - N4'};
                tags_id = [2:7];
                Subdelta_tags = tags_id(logical([app.SubdeltaW.Value app.SubdeltaR.Value app.SubdeltaN1.Value app.SubdeltaN2.Value app.SubdeltaN3.Value app.SubdeltaN4.Value])); if isempty(Subdelta_tags); Subdelta_tags = [1]; end
                Delta_tags = tags_id(logical([app.DeltaW.Value app.DeltaR.Value app.DeltaN1.Value app.DeltaN2.Value app.DeltaN3.Value app.DeltaN4.Value]));if isempty(Delta_tags); Delta_tags = [1]; end
                Theta_tags = tags_id(logical([app.ThetaW.Value app.ThetaR.Value app.ThetaN1.Value app.ThetaN2.Value app.ThetaN3.Value app.ThetaN4.Value]));if isempty(Theta_tags); Theta_tags = [1]; end
                Alpha_tags = tags_id(logical([app.AlphaW.Value app.AlphaR.Value app.AlphaN1.Value app.AlphaN2.Value app.AlphaN3.Value app.AlphaN4.Value]));if isempty(Alpha_tags); Alpha_tags = [1]; end
                Sigma_tags = tags_id(logical([app.SigmaW.Value app.SigmaR.Value app.SigmaN1.Value app.SigmaN2.Value app.SigmaN3.Value app.SigmaN4.Value]));if isempty(Sigma_tags); Sigma_tags = [1]; end
                Beta_tags = tags_id(logical([app.BetaW.Value app.BetaR.Value app.BetaN1.Value app.BetaN2.Value app.BetaN3.Value app.BetaN4.Value]));if isempty(Beta_tags); Beta_tags = [1]; end
                Gammalow_tags = tags_id(logical([app.GammalowW.Value app.GammalowR.Value app.GammalowN1.Value app.GammalowN2.Value app.GammalowN3.Value app.GammalowN4.Value]));if isempty(Gammalow_tags); Gammalow_tags = [1]; end
                Fullband_tags = tags_id(logical([app.FullbandW.Value app.FullbandR.Value app.FullbandN1.Value app.FullbandN2.Value app.FullbandN3.Value app.FullbandN4.Value]));if isempty(Fullband_tags); Fullband_tags = [1]; end
                Sleepband_tags = tags_id(logical([app.SleepbandW.Value app.SleepbandR.Value app.SleepbandN1.Value app.SleepbandN2.Value app.SleepbandN3.Value app.SleepbandN4.Value]));if isempty(Sleepband_tags); Sleepband_tags = [1]; end
                
                bandstgs = {Subdelta_tags, Delta_tags, Theta_tags, Alpha_tags, Sigma_tags, Beta_tags, Gammalow_tags, Fullband_tags, Sleepband_tags};
                bandstgs(~userselected_bands) = []; % removes bands that the user did not select from GUI
                
                % Use GUI parameters for band-specific artifact detection thresholds
                sdelta_arthresh = app.SubdeltaArtifact.Value;
                delta_arthresh = app.DeltaArtifact.Value;
                theta_arthresh = app.ThetaArtifact.Value;
                alpha_arthresh = app.AlphaArtifact.Value;
                sigma_arthresh = app.SigmaArtifact.Value;
                beta_arthresh = app.BetaArtifact.Value;
                lowgamma_arthresh = app.GammalowArtifact.Value;
                fullband_arthresh = app.FullbandArtifact.Value;
                sleepband_arthresh = app.SleepbandArtifact.Value;
                
                %%%STEP 4
                rerefmode = app.RereferenceEEGDatatoButtonGroup.SelectedObject.Text;
                runaddlocs = 1; %Initializing these three settings should probably happen elsewhere - ABF 2022-04-27
                runexppreproc = 1;
                runreref = 1;
                
                imgmode = lower(app.ImageModeButtonGroup.SelectedObject.Text);
                
                remakeeegset = app.ForcerecreationofEEGLABsetfilesCheckBox.Value;
                
                doica = app.RunandsaveICAdecompositionsCheckBox.Value;
                if doica == 1
                    rundoica = 1;
                end
                
                bcimode = app.InterpolatemanuallyidentifiedbadchannelsCheckBox.Value;
                if bcimode == 1
                    bcimode = 'Yes';
                    runbci = 1;
                else
                    bcimode = 'No';
                end
                
                usethetabcs = app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Value;
                if usethetabcs == 1
                    usethetabcs = 'Yes';
                else
                    usethetabcs = 'No';
                end
                
                dodownsample = app.DownsampletoHzCheckBox.Value;
                ARBCI_Settings = app.arbci_settings;
                PreProc_Settings = app.preproc_settings;
                
                %%%STEP 2
                module = app.AnalysisModuleselectoneDropDown.Value;
                
                %Translate module-specific GUI parameters
                switch module
                    case 'Hypnogram'
                        hyp_raster = app.HypnogramRasterPlotCheckbox.Value;
                        if hyp_raster == 1 % Change to match legacy expectation
                            hyp_raster = 'Yes';
                        else
                            hyp_raster = 'No';
                        end
                        hyp_shown4 = 'No';
                        
                    case 'Spectra (EEGLAB)'
                        specfreqs = [app.PlotFreqROIHzMinimumEditField.Value app.PlotFreqROIHzMaximumEditField.Value];
                        spec60filt = app.Apply60HznotchfilterbeforegeneratingspectraCheckBox.Value;
                        
                        if strcmp(app.TimeintervalforspectraButtonGroup.SelectedObject.Text, 'Use all data in selected stages')
                            spectime = {'all'};
                        else
                            spectime = [app.CustomTimeIntervalStart.Value app.CustomTimeIntervalEnd.Value];
                        end
                        
                    case 'Spectra (FieldTrip)'
                        ftspecplotstyle = app.SpectraplotunitsDropDown.Value; %eeglab, abspower, logpower
                        %choosebs = app.FullbandCheckBox.Value;
                        specfreqs = [app.PlotFreqROIMinimumEditField_FT.Value app.PlotFreqROIMaximumEditField_FT.Value];
                        spec60filt = app.Apply60HznotchfilterbeforegeneratingspectraCheckBox_FT.Value;
                        fts_winlength = app.WindowlengthsecEditField_FT.Value;
                        fts_winoverlap = app.WindowoverlapproportionEditField_FT.Value;
                        
                        if strcmp(app.TimeintervalforspectraButtonGroup_FT.SelectedObject.Text, 'Use all data in selected stages')
                            spectime = {'all'};
                        else
                            spectime = [app.CustomTimeIntervalStart_FT.Value app.CustomTimeIntervalEnd_FT.Value];
                        end
                        
                        % Warning if ttest2 or stats toolbox isn't present. -
                        % Needs to be a popup message dialogue after checking if
                        % ttest2 exists (RTM)
                        ttest2_present = exist('ttest2');
                        if length(ttest2_present) <1
                            msgbox('Note: Spectra (FieldTrip) cluster-based testing requires the ttest2 function from the MATLAB statistics toolbox, which is not found on your path. The Spectra (FieldTrip) module will crash if you attempt to run cluster-based permutation analyses.', 'Missing ttest2 function!');
                        end
                        
                    case 'Spectra (newtimef)'
                        specfreqs = [app.PlotFreqROIMinimumEditField_ntf.Value app.PlotFreqROIMaximumEditField_ntf.Value];
                        spec60filt = app.Apply60HznotchfilterbeforegeneratingspectraCheckBox_ntf.Value;
                        
                        if strcmp(app.TimeintervalforspectraButtonGroup_ntf.SelectedObject.Text, 'Use all data in selected stages')
                            spectime = {'all'};
                        else
                            spectime = [app.CustomTimeIntervalStart_ntf.Value app.CustomTimeIntervalEnd_ntf.Value];
                        end
                        
                    case 'Power (Hilbert)'
                        if app.CreatenewenvelopefilesButton.Value == 1
                            makeenvelopes = 1;
                            dohilblp = app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown.Value;
                        else
                            makeenvelopes = 0;
                        end
                        choosefirord = app.FiniteImpulseResponseFIRorderButtonGroup.SelectedObject.Text;
                        switch choosefirord
                            case 'Hardcoded Default'
                                choosefirord = 'Hardcoded';
                            case "Cohen's Algorithm"
                                choosefirord = 'Algorithm';
                        end
                        
                        if any(strcmp(bands, 'theta'))
                            thetamode = lower(app.ThetaFilterModeDropDown.Value);
                        else
                            thetamode = 'none';
                        end
                        
                    case 'Power (newtimef)'
                        perfar = app.RejectartifactsfirstCheckBox.Value;
                        ntfnfreqs = app.ofFrequencies5shortrecommended50mid999longEditField.Value;
                        ntftbs = app.WhatsizetemporalwindowshouldbeusedfornewtimefsecEditField.Value;
                        
                    case 'Spindle Detection (Ferrarelli)'
                        doresampling = app.Resampleto128HzCheckBox.Value;
                        bldrlowpass = app.Lowpasssigmaenvelopeat2HzCheckBox.Value;
                        visualizedata = app.DrawstagesofwaveformabstractionforFerrarellimethodCheckBox.Value;
                        lower_thresh_ratio = app.FerrarelliThresholdLow.Value;
                        upper_thresh_ratio = app.FerrarelliThresholdHigh.Value;
                        
                        uiwait(msgbox({'If you use this module for any academic purpose, please be sure to cite the following papers in your work:';...
                            '';'Ferrarelli, F., Huber, R., Peterson, M. J., Massimini, M., Murphy, M., Riedner, B. A., Watson, A., Bria, P., & Tononi, G. (2007). Reduced sleep spindle activity in schizophrenia patients. The American Journal of Psychiatry, 164(3), 483–492. https://doi.org/10.1176/ajp.2007.164.3.483';...
                            '';'McClain, I. J., Lustenberger, C., Achermann, P., Lassonde, J. M., Kurth, S., & LeBourgeois, M. K. (2016). Developmental changes in sleep spindle characteristics and sigma power across early childhood. Neural Plasticity, 2016, 3670951. https://doi.org/10.1155/2016/3670951'},...
                            'Citations required','modal'));
                        
                    case 'PAC (Muehlroth)'
                        %%Pull/translate any Muehlroth-specific GUI settings here - ABF 2022-02-23
                        
                    case 'TMRtistry (WIP)'
                        TMRtistry_Settings = app.tmrtistry_settings;
                        TMRtistry_Script = app.tmrtistry_script;
                                    
                    case 'Envelope Viewer'
                        % Establish stagelist for Envelope Viewer code
                        stagelist = {'W', 'R', 'N1', 'N2', 'N3'};
                        
                        blchoice = app.BaselineRemovalChoiceCheckBox.Value;
                        if blchoice == 1
                            blchoice = 'Yes';
                        else
                            blchoice = 'No';
                        end
                        blremwind = app.BaselineRemovalWindowLengthNumericEditField.Value;
                        
                        gblchoice = app.GlobalBaslineRemovalChoiceCheckBox.Value;
                        if gblchoice == 1
                            gblchoice = 'Yes';
                        else
                            gblchoice = 'No';
                        end
                        
                        archoice = app.ArtifactNaNChoiceCheckBox.Value;
                        if archoice == 1
                            archoice = 'Yes';
                        else
                            archoice = 'No';
                        end
                        
                        omitempties = app.OmitEmptyEnvelopesCheckBox.Value;
                        if omitempties == 1
                            omitempties = 'yes'; % match the original penviewer code
                        else
                            omitempties = 'no';
                        end
                        
                        clnmeth = app.ArtifactChoiceDropdown.Value;
                        medsmoothwind = app.MedianSmoothWindowNumericEditField.Value;
                        
                        avgacross = app.AverageenvelopesacrossButtonGroup.SelectedObject.Text;
                        if strcmp(avgacross, 'chans')
                            statsmethod = app.QuantifyenvelopesButtonGroup.SelectedObject.Text;
                        elseif strcmp(avgacross, 'time')
                            statsmethod = 'kurth';
                        end
                        truemeasure = app.WhatenvelopetypeButtonGroup.SelectedObject.Text;
                        collatenew = app.CollateorloadenvelopesButtonGroup.SelectedObject.Text;
                        temporalroi = [app.EnvViewerTempROILowNumericEditField.Value app.EnvViewerTempROIHighNumericEditField.Value];
                        if any(temporalroi == 999)
                            temporalroi = 'all';
                        end
                        
                        srate = str2num(app.EEGSamplingFrequencyHzEditField.Value);
                        
                        if any(strcmp(bands, 'theta'))
                            thetamode = lower(app.ThetaFilterModeDropDown_EnvViewer.Value);
                        else
                            thetamode = 'none';
                        end
                        
                    case 'Spectrogram Viewer'
                        data_nfreqs = app.HowmanyfrequenciesEditField.Value;
                        data_elec = app.WhatelectrodeEditField.Value;
                        
                        %Should pop up a message at least saying that only the first selected band will be used (or enable multiband processing, or restrict GUI selection to one band) - ABF 2022-02-16
                        if length(bands) == 1
                            data_band = bands{1};
                        else
                            error('ERROR: Spectrogram Viewer only supports a single band selection. Please return to the GUI, select only a single band, and try again.');
                        end
                        
                        if app.SavetifsCheckBox.Value == 1
                            savetifs = 'yes';
                        elseif app.SavetifsCheckBox.Value == 0
                            savetifs = 'no';
                        end
                        if app.SavetifmetasCheckBox.Value == 1
                            savetifmetas = 'yes';
                        elseif app.SavetifmetasCheckBox.Value == 0
                            savetifmetas = 'no';
                        end
                end
                
                %Initialize settings specific to spectral vs. non-spectral modules
                if strfind(lower(module), 'spectra')
                    if length(bands) == 1
                        eval(['curband_tags = ', upper(bands{1}(1)), lower(bands{1}(2:end)), '_tags;'])
                    else
                        error('ERROR: Spectra methods only support a single band selection. Somehow you are trying to run a spectra method with more than one band selected; please return to the GUI, select only a single band, and try again.');
                    end
                    spectimestg = strrep(knowntags([1 curband_tags]),' ', '');
                elseif isempty(strfind(lower(module), 'spectra'))
                    % Band-specific staging
                    for b = 1:length(bands)
                        eval([bands{b} 'stages = bandstgs{b};']);
                        eval([bands{b} 'stages = strrep(knowntags(' bands{b} 'stages),'' '', '''');']);
                    end
                    clear b;
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Experiment-specific setup
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                switch app.ExperimentnameDropDown.Value
                    case 'ADHD_SE_ABF'
                        % Night key Experiment-specific switch case
                        [~,~,nightkey] = xlsread([app.eegrootfolder 'PID-Night_Key.csv']);
                        nightkey(2:end,3) = cellfun(@num2str, nightkey(2:end, 3), 'UniformOutput', 0);
                        nightkey(2:end,5) = cellfun(@num2str, nightkey(2:end, 5), 'UniformOutput', 0);
                        for r = 1:size(nightkey,1)
                            if length(nightkey{r,3}) == 5
                                nightkey{r,3} = ['0' nightkey{r,3}];
                            end
                            if length(nightkey{r,5}) == 5
                                nightkey{r,5} = ['0' nightkey{r,5}];
                            end
                        end
                    otherwise
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Run ARBCI_Settings file
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if ~isempty(ARBCI_Settings)
                    eval(ARBCI_Settings)
                else
                    if strcmp(bcimode, 'Yes')
                        warning('Bad channel information was not found. Please use the "BCI Settings: Import" button in the GUI to select the text file containing bad channel information.')
                        waitfor(msgbox('Bad channel information was not found. Please use the "BCI Settings: Import" button in the GUI to select the text file containing bad channel information.'))
                        return
                    end
                    pidbcichans = [];
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% MAIN PROCESSING ROUTINE
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                otic = tic; %Start processing timer
                errs = []; %Init error catching variable
                figure(app.UIFigure); % Refocus GUI as main window
                
                %This conditional differentiates between modules that process data and modules that view previously processed data. - ABF 2022-02-23
                if ~any(strcmp(module, {'Envelope Viewer', 'Spectrogram Viewer'}))
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% LOOP OVER PARTICIPANTS
                    %%%%%%%%%%%%%%%%%%%%%%%%%%
                    for sub = 1:length(app.eegfiles)
                        
                        %Adjust eegfp and eegfn to account for whether EEG file is in a subfolder - ABF 2019-03-27
                        if any(strfind(app.eegfiles{sub}, '/'))
                            eegfileparts = strsplit(app.eegfiles{sub}, '/');
                            eegfp = [strjoin(eegfileparts(1:length(eegfileparts)-1), '/'), '/'];
                            eegfn = eegfileparts{length(eegfileparts)};
                        else
                            eegfp = app.eegrootfolder;
                            eegfn = app.eegfiles{sub};
                        end
                        
                        %Load current participant settings from montage key if present
                        if exist('montagekey')
                            mkpidparts = strsplit(eegfn, '_');
                            mkpidparts = [mkpidparts(1:length(mkpidparts)-1), strsplit(mkpidparts{length(mkpidparts)}, '.')];
                            mkpidcol = find(strcmp(montagekey(1,:), 'PID'));
                            mkscorcol = find(strcmp(montagekey(1,:), 'Scoring'));
                            mkcondcols = find(~strcmp(montagekey(1,:), 'PID') & ~strcmp(montagekey(1,:), 'Scoring'));
                            
                            mkcurrow = find(strcmp(montagekey(:,mkpidcol), mkpidparts{1}));
                            mkcurcol = find(strcmp(montagekey(1,:), mkpidparts{2}));
                            
                            stgmethod = lower(montagekey{mkcurrow, mkscorcol});
                            montageused = lower(montagekey{mkcurrow, mkcurcol});
                        end
                        
                        setfp = [eegfp 'SETs/'];
                        if ~exist(setfp,'dir')
                            mkdir(setfp);
                        end
                        
                        ptic = tic;
                        pid = regexprep(eegfn, '\.edf|\.EDF|\.vhdr', '');
                        
                        %Identify which staging file to use, skip participant if none exists
                        if ~strcmp(stgmethod, '') %Allow hidden option of running without staging info - ABF 2020-07-01
                            curstgidx = find(~cellfun(@isempty, strfind(app.stgfiles, [pid '.'])));
                            if isempty(curstgidx)
                                curstgidx = find(~cellfun(@isempty, strfind(app.stgfiles, [pid '_Notations.'])));
                                if isempty(curstgidx)
                                    disp(['ERROR: No notation file found for ' eegfn '! Skipping data file...']);
                                    continue
                                end
                            end
                            if length(curstgidx) > 1
                                whichstg = listdlg('PromptString', sprintf('The following stage notation files were found. Which of these do you want to use?'),...
                                    'SelectionMode', 'multiple', 'Name', 'Which EEG files?', 'ListSize', [500 300], 'ListString', app.stgfiles(curstgidx));
                                curstgidx = curstgidx(whichstg);
                            end
                        end
                        
                        %Import or load EEG data
                        if remakeeegset == 1 || exist([setfp pid '.set']) ~= 2 %Start from scratch
                            %Reset EEGLAB and ERPLAB data structures
                            ALLEEG = [];
                            ALLERP = [];
                            EEG = [];
                            ERP = [];
                            STUDY = [];
                            CURRENTSET = 0;
                            CURRENTERP = 0;
                            CURRENTSTUDY = 0;
                            ALLCOM = {};
                            ALLERPCOM = {};
                            
                            %Import EEG data to EEGLAB
                            %app.AnalyzeButton.Text = 'Loading EEG data...';
                            progfig = uifigure; progdlg = uiprogressdlg(progfig, 'Title', ['Importing ' eegfn '...'], 'Indeterminate', 'On');
                            if regexp(lower(eegfn), '\.edf$')
                                EEG = pop_biosig([eegfp eegfn], 'importevent', 'off');
                                [ALLEEG EEG CURRENTSET] = pop_newset(ALLEEG, EEG, 0,'setname',eegfn,'gui','off');
                            elseif regexp(lower(eegfn), '\.vhdr$')
                                EEG = pop_loadbv(eegfp, eegfn);
                                [ALLEEG EEG CURRENTSET] = pop_newset(ALLEEG, EEG, 0,'setname',eegfn,'gui','off');
                            end
                            %app.AnalyzeButton.Text = 'EEG data loaded!';
                            close(progfig);
                            srate_orig = EEG.srate;
                            srate = srate_orig;
                            
                            %Downsample high srate data if requested
                            if dodownsample == 1
                                %app.AnalyzeButton.Text = 'Downsampling EEG...';
                                disp(['Downsampling EEG data to ' num2str(app.DownsampledFrequencyNumericEditField.Value) ' Hz...']);
                                progfig = uifigure; progdlg = uiprogressdlg(progfig, 'Title', ['Downsampling ' eegfn ' from ' num2str(thinkfs) ' to ' num2str(app.DownsampledFrequencyNumericEditField.Value) ' Hz...'], 'Indeterminate', 'On');
                                EEG = pop_resample(EEG, app.DownsampledFrequencyNumericEditField.Value);
                                [ALLEEG EEG CURRENTSET] = pop_newset(ALLEEG, EEG, 1,'overwrite','on','gui','off');
                                srate_ds = app.DownsampledFrequencyNumericEditField.Value;
                                srate = srate_ds;
                                close(progfig);
                            else
                                srate_ds = [];
                            end
                            
                            %%%Load tag info
                            %Fill briefcase for pow_gettags
                            tagpow.stgmethod = stgmethod;
                            tagpow.stgfiles = app.stgfiles;
                            tagpow.curstgidx = curstgidx;
                            tagpow.eegfp = eegfp;
                            tagpow.eegfn = eegfn;
                            tagpow.dodownsample = dodownsample;
                            tagpow.srate_orig = srate_orig;
                            tagpow.srate_ds = srate_ds;
                            tagpow.extfp = extfp;
                            tagpow.knowntags = knowntags;
                            
                            tags = pow_gettags(tagpow);
                            
                            %Add in TMR cue events if present
                            if ~strcmp(stgmethod, '')
                                cuetagtypes = {''};
                                if ~exist('module') || strcmp(module, 'TMRtistry (WIP)')
                                    if length(TMRtistry_Settings) > 0
                                        eval(TMRtistry_Settings)
                                    else
                                        warning('TMRtistry_Settings file was not found. Please use the "Import... --> TMRtistry Settings" GUI button to select the appropriate text file.')
                                    end
                                end
                            end
                            
                            % Write tag information into EEGLAB .set
                            if ~strcmp(stgmethod, '')
                                %Write processed stage notations out to a temporary text file for
                                %importing as EEG events.
                                outfid = fopen([eegfp 'temp.txt'], 'w+');
                                fprintf(outfid, '%s\t%s\n', 'latency', 'type');
                                for row = 1:size(tags,1)
                                    fprintf(outfid, '%s\t%s\n', num2str(tags{row,2}),  strrep(tags{row,1}, ' ', ''));
                                end
                                fclose(outfid);
                                
                                %Import temporary stage notation text file as EEG events.
                                EEG = pop_importevent( EEG, 'event',[eegfp 'temp.txt'],'fields',{'latency' 'type'},'skipline',1,'timeunit',NaN);
                                [ALLEEG EEG] = eeg_store(ALLEEG, EEG, CURRENTSET);
                                delete([eegfp 'temp.txt']);
                            end
                            
                            %Save EEGLAB format .set/.fdt files
                            EEG = pop_saveset( EEG, 'filename', [pid '.set'], 'filepath', setfp);
                            
                        else %Load a saved preprocessed dataset
                            %Clear preprocessing step run flags
                            runaddlocs = 0;
                            runexppreproc = 0;
                            runreref = 0;
                            rundoica = 0;
                            runbci = 0;
                            if dodownsample == 1
                                srate_ds = app.DownsampledFrequencyNumericEditField.Value;
                            else
                                srate_ds = [];
                            end
                            
                            %Determine which file to load
                            procstring = '';
                            if exist([setfp pid procstring '_reref.set']) == 2
                                procstring = [procstring '_reref'];
                                if doica == 1
                                    if exist([setfp pid procstring '_ica.set']) == 2
                                        procstring = [procstring '_ica'];
                                    else
                                        rundoica = 1;
                                    end
                                end
                                if strcmp(bcimode, 'Yes') && ~isempty(find(strcmp(pid, pidbcichans(:,1))))
                                    if exist([setfp pid procstring '_bci.set']) == 2
                                        procstring = [procstring '_bci'];
                                    else
                                        runbci = 1;
                                    end
                                end
                            else
                                runaddlocs = 1;
                                runexppreproc = 1;
                                runreref = 1;
                            end
                            
                            %Load appropriate EEG file
                            disp(['Loading EEG file ' setfp pid procstring '.set...' ]);
                            ALLEEG = [];
                            EEG = [];
                            CURRENTSET = [];
                            ALLCOM = [];
                            EEG = pop_loadset('filename',[pid procstring '.set'],'filepath',setfp);
                            
                            %Check whether requested reference was used. If not, redo preprocessing.
                            %Find mastoids
                            leftmast = find(strcmp({EEG.chanlocs.labels}, 'M1'));
                            rightmast = find(strcmp({EEG.chanlocs.labels}, 'M2'));
                            if isempty(leftmast)
                                leftmast = find(strcmp({EEG.chanlocs.labels}, 'A1'));
                            end
                            if isempty(rightmast)
                                rightmast = find(strcmp({EEG.chanlocs.labels}, 'A2'));
                            end
                            
                            disp('Calculating likelihood of averaged mastoid reference...');
                            probavgmast = sum(round(EEG.data(leftmast,:),4) == -round(EEG.data(rightmast,:),4)) / EEG.pnts;
                            disp(['Left mastoid is the perfect inverse of right mastoid ' num2str(probavgmast * 100) '% of the time (EEG rounded to 4 sig figs)']);
                            runscratchload = 0;
                            if probavgmast >= 0.75
                                if strcmp(app.RereferenceEEGDatatoButtonGroup.SelectedObject.Text, 'AvgMast')
                                    disp('Assuming high inverse correlation of left/right mastoids means loaded data have averaged mastoid reference as requested, continuing...');
                                else
                                    disp(['High inverse correlation of mastoid channels in loaded data is suspicious for requested reference ' rerefmode '. Loading un-rereferenced dataset and re-doing preprocessing.']);
                                    runscratchload = 1;
                                end
                            else
                                if strcmp(app.RereferenceEEGDatatoButtonGroup.SelectedObject.Text, 'AvgMast')
                                    disp('Inverse correlation of left/right mastoids is too low in loaded data for averaged mastoid reference. Reperforming preprocessing...')
                                    runscratchload = 1;
                                end
                            end
                            
                            if runscratchload == 1
                                ALLEEG = [];
                                EEG = [];
                                CURRENTSET = [];
                                ALLCOM = [];
                                EEG = pop_loadset('filename',[pid '.set'],'filepath',setfp);
                                
                                procstring = '';
                                runaddlocs = 1;
                                runexppreproc = 1;
                                runreref = 1;
                                if doica == 1
                                    rundoica = 1;
                                end
                                if strcmp(bcimode, 'Yes') && ~isempty(find(strcmp(pid, pidbcichans(:,1))))
                                    runbci = 1;
                                end
                            end
                            srate_orig = EEG.srate;
                            srate = srate_orig;
                            
                            %%%Load tag info
                            %Fill briefcase for pow_gettags
                            tagpow.stgmethod = stgmethod;
                            tagpow.stgfiles = app.stgfiles;
                            tagpow.curstgidx = curstgidx;
                            tagpow.eegfp = eegfp;
                            tagpow.eegfn = eegfn;
                            tagpow.dodownsample = dodownsample;
                            tagpow.srate_orig = srate_orig;
                            tagpow.srate_ds = srate_ds;
                            tagpow.extfp = extfp;
                            tagpow.knowntags = knowntags;
                            
                            tags = pow_gettags(tagpow);
                            
                            %Load TMRtistry settings if appropriate
                            if ~exist('cuetagtypes')
                                cuetagtypes = {''};
                                if ~exist('module') || strcmp(module, 'TMRtistry (WIP)')
                                    if length(TMRtistry_Settings) > 0
                                        eval(TMRtistry_Settings)
                                    else
                                        warning('TMRtistry_Settings file was not found. Please use the "Import... --> TMRtistry Settings" GUI button to select the appropriate text file.')
                                    end
                                end
                            end
                        end
                        
                        if runaddlocs == 1 && isempty(EEG.chanlocs)
                            %Create temporary copy of data to use for channel location lookup
                            forlocs = EEG;
                            if any(strfind(lower(eegfn), '.edf'))
                                %Split channel names from reference channel names (NOTE: THIS IS
                                %DESIGNED AROUND TWIN CHANNEL NAMING CONVENTIONS AND NEEDS TO BE
                                %UPDATED FOR COMPATIBILITY WITH BV SAVED .EDFs)
                                for l = 1:length(forlocs.chanlocs)
                                    forlocs.chanlocs(l).labels = strsplit(forlocs.chanlocs(l).labels, '-');
                                    forlocs.chanlocs(l).labels = forlocs.chanlocs(l).labels{1};
                                end
                            elseif any(strfind(lower(eegfn), '.vhdr'))
                                %
                            end
                            %Lookup channel locations based on channel names
                            forlocs=pop_chanedit(forlocs, 'lookup',strrep(which('eeglab'), 'eeglab.m', ['plugins/', dipfitstr, '/standard_BESA/standard-10-5-cap385.elp']),...
                                'save',[setfp, pid, '.ced']);
                            %Port identified channel locations back to main EEG data
                            for l = 1:length(EEG.chanlocs)
                                EEG.chanlocs(l).ref= forlocs.chanlocs(l).ref;
                                EEG.chanlocs(l).theta= forlocs.chanlocs(l).theta;
                                EEG.chanlocs(l).radius= forlocs.chanlocs(l).radius;
                                EEG.chanlocs(l).X= forlocs.chanlocs(l).X;
                                EEG.chanlocs(l).Y= forlocs.chanlocs(l).Y;
                                EEG.chanlocs(l).Z= forlocs.chanlocs(l).Z;
                                EEG.chanlocs(l).sph_theta= forlocs.chanlocs(l).sph_theta;
                                EEG.chanlocs(l).sph_phi= forlocs.chanlocs(l).sph_phi;
                                EEG.chanlocs(l).sph_radius= forlocs.chanlocs(l).sph_radius;
                                EEG.chanlocs(l).type= forlocs.chanlocs(l).type;
                                EEG.chanlocs(l).urchan= forlocs.chanlocs(l).urchan;
                            end
                            clear forlocs
                        end
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%% Run PreProc_Settings file (custom preprocessing)
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        stepout = 0;
                        if (runexppreproc == 1)
                            % Update user on current progress
                            app.AnalyzeButton.FontSize = 12;
                            app.AnalyzeButton.Text = 'Running custom preprocessing...';
                            
                            montisknown = 0;
                            
                            if length(PreProc_Settings) > 1
                                eval(PreProc_Settings)
                            else
                                warning('Custom preprocessing settings were not found. Please use the "Preprocessing Settings: Import" button to select the appropriate text file.')
                                waitfor(msgbox('Custom preprocessing settings were not found. Please use the "Preprocessing Settings: Import" button to select the appropriate text file.'));
                                stepout = 1;
                            end
                        end
                        
                        if stepout == 1 % replaced all continues with assigning stepout as 1. issues with using continue command in eval(txtfile). -RTM
                            continue
                        end
                        
                        %%%Perform experiment-agnostic preprocessing
                        %Double-check sampling rate
                        if srate ~= EEG.srate
                            error(['ERROR: Provided sample rate (' num2str(srate) ' Hz) is different from actual sample rate (' num2str(EEG.srate) ' Hz). Run again using the correct sampling rate.']);
                        end
                        
                        %Check electrode montage against other recordings in dataset
                        if ~exist('checkchannum')
                            checkchannum = EEG.nbchan;
                        end
                        if ~exist('checkchanlist')
                            checkchanlist = {EEG.chanlocs.labels};
                        end
                        if EEG.nbchan == checkchannum
                            if ~all(strcmp(checkchanlist, {EEG.chanlocs.labels}))
                                warnstring = ['Recording ' eegfn ' does not have the same electrode montage as the first recording in this dataset, which means re-referencing and other steps in this program may behave in unexpected ways. CHECK YOUR DATA MANUALLY to find out what is going on! - ABF'];
                                warning(warnstring);
                                uiwait(msgbox(warnstring));
                            end
                        else
                            warnstring = ['Recording ' eegfn ' does not have the same number of electrodes as the first recording in this dataset, which means re-referencing and other steps in this program may behave in unexpected ways. CHECK YOUR DATA MANUALLY to find out what is going on! - ABF'];
                            warning(warnstring);
                            uiwait(msgbox(warnstring));
                        end
                        
                        %Find mastoids
                        leftmast = find(strcmp({EEG.chanlocs.labels}, 'M1'));
                        rightmast = find(strcmp({EEG.chanlocs.labels}, 'M2'));
                        if isempty(leftmast)
                            leftmast = find(strcmp({EEG.chanlocs.labels}, 'A1'));
                        end
                        if isempty(rightmast)
                            rightmast = find(strcmp({EEG.chanlocs.labels}, 'A2'));
                        end
                        
                        %%%RE-REFERENCING
                        if runreref == 1
                            %Implement chosen re-referencing scheme
                            if strcmp(rerefmode, 'AvgMast')
                                refstring = ['(ch' num2str(leftmast) ' + ch' num2str(rightmast) ')'];
                                numrefchans = 2;
                                %Write re-referencing equations out to temporary text file for use by pop_eegchanoperator.
                                outfid = fopen([eegfp 'tempreref.txt'], 'w+');
                                for row = 1:EEG.nbchan
                                    fprintf(outfid, '%s\n', ['nch' num2str(row) ' = ch' num2str(row) ' - ( ' refstring '/' num2str(numrefchans) ' ) Label ' EEG.chanlocs(row).labels]);
                                end
                                fclose(outfid);
                            elseif strcmp(rerefmode, 'ContraMast')
                                warning('Contralateral mastoid re-referencing selected. This program will extract the number (or z) from EEG electrode labels, then use that to determine whether the left or right mastoid is contralateral. For midline electrodes, it alternates between left/right mastoids. EMG and EOG electrodes are not re-referenced using this setting. - ABF 2019-06-25')
                                chlist = {EEG.chanlocs.labels};
                                chlist_suf = cellfun(@(x) x(length(x)), chlist, 'UniformOutput', 0);
                                noneeg = [find(~cellfun(@isempty, strfind(lower(chlist), 'eog'))) find(~cellfun(@isempty, strfind(lower(chlist), 'chin')))];
                                lefthem_list = find(ismember(lower(chlist_suf), {'1', '3', '5', '7', '9', '11', '13', '15', '17', '19'}));
                                lefthem_list = setdiff(lefthem_list, [leftmast noneeg]);
                                righthem_list = find(ismember(lower(chlist_suf), {'2', '4', '6', '8', '10', '12', '14', '16', '18', '20'}));
                                righthem_list = setdiff(righthem_list, [rightmast noneeg]);
                                midline_list = find(strcmpi(chlist_suf, 'z'));
                                
                                %Write re-referencing equations out to temporary text file for use by pop_eegchanoperator.
                                outfid = fopen([eegfp 'tempreref.txt'], 'w+');
                                zcount = 0;
                                for row = 1:EEG.nbchan
                                    if any(row == lefthem_list)
                                        fprintf(outfid, '%s\n', ['nch' num2str(row) ' = ch' num2str(row) ' - ch' num2str(leftmast) ' Label ' EEG.chanlocs(row).labels]);
                                    elseif any(row == righthem_list)
                                        fprintf(outfid, '%s\n', ['nch' num2str(row) ' = ch' num2str(row) ' - ch' num2str(rightmast) ' Label ' EEG.chanlocs(row).labels]);
                                    elseif any(row == midline_list)
                                        zcount = zcount + 1;
                                        if mod(zcount, 2) == 0
                                            fprintf(outfid, '%s\n', ['nch' num2str(row) ' = ch' num2str(row) ' - ch' num2str(leftmast) ' Label ' EEG.chanlocs(row).labels]);
                                        elseif mod(zcount, 2) == 1
                                            fprintf(outfid, '%s\n', ['nch' num2str(row) ' = ch' num2str(row) ' - ch' num2str(rightmast) ' Label ' EEG.chanlocs(row).labels]);
                                        end
                                    else
                                        fprintf(outfid, '%s\n', ['nch' num2str(row) ' = ch' num2str(row) ' Label ' EEG.chanlocs(row).labels]);
                                    end
                                end
                                fclose(outfid);
                            elseif strcmp(rerefmode, 'Custom')
                                %Let user select as many electrodes as she/he wants, then
                                %note the labels and the number of channels. Build a ref string
                                %similar to avg mast above, and divide by numrefchans.
                                %Make sure to only have this run for the first participant. Maybe implement
                                %some sort of global tracker to notify user if different participants have
                                %different montages. - ABF 2019-06-05
                                if custrefexist == 0
                                    refchans = listdlg('PromptString', 'Choose your reference channel(s):', 'SelectionMode', 'multiple',...
                                        'ListString', {EEG.chanlocs.labels}, 'Name', 'Custom reference', 'ListSize', [250 300]);
                                    refchans = {EEG.chanlocs(refchans).labels};
                                    numrefchans = length(refchans);
                                    custrefexist = 1;
                                end
                                %Find reference electrode indices for current file, set reference string
                                refindices = find(ismember({EEG.chanlocs.labels}, refchans));
                                refstring = ['(ch' num2str(refindices(1))];
                                if numrefchans > 1
                                    for chan = 2:numrefchans
                                        refstring = [refstring ' + ch' num2str(refindices(chan))];
                                    end
                                end
                                refstring = [refstring ')'];
                                %Write re-referencing equations out to temporary text file for use by pop_eegchanoperator.
                                outfid = fopen([eegfp 'tempreref.txt'], 'w+');
                                for row = 1:EEG.nbchan
                                    fprintf(outfid, '%s\n', ['nch' num2str(row) ' = ch' num2str(row) ' - ( ' refstring '/' num2str(numrefchans) ' ) Label ' EEG.chanlocs(row).labels]);
                                end
                                fclose(outfid);
                            end
                            
                            %Re-reference EEG data
                            app.AnalyzeButton.Text = 'Re-referencing data';
                            EEG = pop_eegchanoperator( EEG, [eegfp 'tempreref.txt'], 'ErrorMsg', 'popup', 'Warning', 'on' ); %<-- Takes an excessively long time. Alternate faster approaches? Any reason not to manually re-reference?- ABF 2022-04-21
                            delete([eegfp 'tempreref.txt']);
                            %Re-add channel locations after re-referencing
                            EEG=pop_chanedit(EEG, 'lookup',[fileparts(which('eeglab')) '/plugins/' dipfitstr '/standard_BESA/standard-10-5-cap385.elp']);
                            
                            %Save tag-added, re-referenced dataset to disk for quality control review #2
                            procstring = '_reref';
                            EEG = pop_saveset( EEG, 'filename', [pid procstring '.set'], 'filepath', setfp);
                        end
                        
                        %Perform ICA decomposition if requested, save dataset with weights
                        if doica == 1
                            if rundoica == 1
                                procstring = [procstring '_ica'];
                                EEG = pop_runica(EEG, 'extended',1,'interupt','on');
                                EEG = pop_saveset( EEG, 'filename', [pid procstring '.set'], 'filepath', setfp);
                            end
                        end
                        
                        %Perform bad channel interpolation if requested, save BCI-applied dataset
                        if strcmp(bcimode, 'Yes')
                            if runbci == 1
                                pidbcirow = find(strcmp(pid, pidbcichans(:,1)));
                                if ~isempty(pidbcirow)
                                    %Find data matrix positions of bad channels
                                    chanstobci = {strsplit(pidbcichans{pidbcirow,2}, '|')};
                                    curbci = [];
                                    for tobci = 1:length(chanstobci{1})
                                        curbci = [curbci find(strcmp(lower({EEG.chanlocs.labels}), lower(chanstobci{1}{tobci})))];
                                    end
                                    
                                    disp(['Interpolating bad channels ' strjoin(chanstobci{1}, ', ') ' for ' pid '...']);
                                    app.AnalyzeButton.Text = 'Running BCI...';
                                    
                                    %Perform interpolation of bad channels
                                    EEG = pop_interp(EEG, curbci, 'spherical');
                                    
                                    %Save tag-added, re-referenced, bad-channel interpolated dataset to disk
                                    procstring = [procstring '_bci'];
                                    EEG = pop_saveset( EEG, 'filename', [pid procstring '.set'], 'filepath', setfp);
                                    app.AnalyzeButton.Text = 'BCI complete!';
                                end
                            end
                        end
                        
                        if subsearch == 1
                            app.cursubdir = strsplit(EEG.filepath, filesep);
                            if length(app.cursubdir) == 1
                                app.cursubdir = strsplit(EEG.filepath, '/');
                            end
                            app.cursubdir = app.cursubdir{end-1};
                            pidstring = [app.cursubdir '-' strrep(EEG.filename, '.set', '')];
                        else
                            pidstring = strrep(EEG.filename, '.set', '');
                        end
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%
                        %%% EEGLAB-BASED MODULES
                        %%%%%%%%%%%%%%%%%%%%%%%%
                        unexcised = EEG;
                        if ~any(strcmp(module,{'Spindle Detection (Ferrarelli)','PAC (Muehlroth)'}))
                            clear tfo;
                            
                            %Extract spectral power using EEGLAB
                            for b = 1:length(bands)
                                try
                                    if strcmp(bands{b}, 'theta') && strcmp(module, 'Power (Hilbert)')
                                        thetamodesuf = thetamode;
                                    else
                                        thetamodesuf = '';
                                    end
                                    
                                    %Reset EEG to full recording (all sleep stages)
                                    EEG = unexcised;
                                    
                                    if ~isempty(strfind(lower(module), 'spectra'))
                                        if ~strcmp(stgmethod, '')
                                            keepstages = spectimestg;
                                        else
                                            keepstages = {'NoStageInfo'};
                                            spectimestg = 'NoStageInfo';
                                        end
                                    else
                                        if ~strcmp(stgmethod, '')
                                            eval(['keepstages = ' bands{b} 'stages;'])
                                        else
                                            keepstages = {'NoStageInfo'};
                                        end
                                        %TODO: THINK ABOUT FREQUENCY BAND SPECIFIC WINDOWING OF THE
                                        %HILBERT ENVELOPE TRANSFORMATION. - ABF 2018-09-12
                                        if strcmp(bands{b}, 'subdelta')
                                            filtorder = 2;
                                            arthresh = sdelta_arthresh;
                                            filtdesign = 'butter';
                                            remdc = 'off';
                                        elseif strcmp(bands{b}, 'delta')
                                            filtorder = 2; %Note: this results in reduced passband gain, but higher orders are not recommended for such low highpasses...
                                            arthresh = delta_arthresh;
                                            filtdesign = 'butter';
                                            remdc = 'off';
                                        elseif strcmp(bands{b}, 'theta')
                                            if strcmp(choosefirord, 'Hardcoded')
                                                filtorder = 164;
                                            elseif strcmp(choosefirord, 'Algorithm')
                                                filtorder = 3*(EEG.srate/bandfreqs{b}(1));
                                            end
                                            arthresh = theta_arthresh;
                                            filtdesign = 'fir';
                                            remdc = 'on';
                                        elseif strcmp(bands{b}, 'alpha')
                                            if strcmp(choosefirord, 'Hardcoded')
                                                filtorder = 164;
                                            elseif strcmp(choosefirord, 'Algorithm')
                                                filtorder = 3*(EEG.srate/bandfreqs{b}(1));
                                            end
                                            arthresh = alpha_arthresh;
                                            filtdesign = 'fir';
                                            remdc = 'on';
                                        elseif strcmp(bands{b}, 'sigma')
                                            if strcmp(choosefirord, 'Hardcoded')
                                                filtorder = 164;
                                            elseif strcmp(choosefirord, 'Algorithm')
                                                filtorder = 3*(EEG.srate/bandfreqs{b}(1));
                                            end
                                            arthresh = sigma_arthresh;
                                            filtdesign = 'fir';
                                            remdc = 'on';
                                        elseif strcmp(bands{b}, 'beta')
                                            filtorder = 3*(EEG.srate/bandfreqs{b}(1));
                                            arthresh = beta_arthresh;
                                            filtdesign = 'fir';
                                            remdc = 'on';
                                        elseif strcmp(bands{b}, 'lowgamma')
                                            filtorder = 3*(EEG.srate/bandfreqs{b}(1));
                                            arthresh = lowgamma_arthresh;
                                            filtdesign = 'fir';
                                            remdc = 'on';
                                        elseif strcmp(bands{b}, 'sleepband')
                                            filtorder = 2;
                                            arthresh = sleepband_arthresh;
                                            filtdesign = 'butter';
                                            remdc = 'off';
                                        elseif strcmp(bands{b}, 'fullband')
                                            filtorder = 2;
                                            arthresh = fullband_arthresh;
                                            filtdesign = 'butter';
                                            remdc = 'off';
                                        end
                                        
                                        %Ensure even FIR filter order
                                        if strcmp(filtdesign, 'fir') && mod(filtorder,2) == 1
                                            filtorder = filtorder + 1;
                                        end
                                    end
                                    
                                    if ~strcmp(stgmethod, '')
                                        %Isolate appropriate sleep stage(s) part 1: identify chosen stages
                                        app.AnalyzeButton.Text = ['Identifying requested sleep stages ' strjoin(keepstages, ', ') ' for ' pid ' (ignoring cue related tags ' strjoin(cuetagtypes, ', ') ')...'];
                                        boundpad = 5;
                                        evs = {EEG.event.type};
                                        hypnochanges = 0;
                                        hcbounds = zeros(1,2);
                                        for ev = 1:length(evs)
                                            if any(strcmp(evs{ev}, keepstages))
                                                if hcbounds(size(hcbounds,1),2) < EEG.event(ev).latency
                                                    hypnochanges = hypnochanges + 1;
                                                    hcbounds(hypnochanges,1) = EEG.event(ev).latency;
                                                    ticker = 1;
                                                    while hcbounds(hypnochanges,2) == 0
                                                        if ev == length(evs) || ev+ticker == length(evs)
                                                            hcbounds(hypnochanges,2) = EEG.pnts-boundpad;
                                                        elseif ~any(strcmp(evs{ev+ticker}, [keepstages cuetagtypes]))
                                                            hcbounds(hypnochanges,2) = EEG.event(ev+ticker).latency;
                                                        else
                                                            ticker = ticker + 1;
                                                        end
                                                    end
                                                end
                                            end
                                        end
                                    else
                                        hypnochanges = 1;
                                    end
                                    
                                    %If any chosen stages were identified...
                                    if hypnochanges > 0
                                        if ~strcmp(stgmethod, '')
                                            %Isolate appropriate sleep stage(s) part 2: excise unwanted stages
                                            app.AnalyzeButton.Text = ['Excising EEG from unrequested sleep stages ' strjoin(setdiff(evs, [keepstages cuetagtypes], 'stable'), ', ') ' for ' pid '...'];
                                            if hcbounds(size(hcbounds,1),2)+boundpad < EEG.pnts-1
                                                EEG = pop_select( EEG,'notime',([hcbounds(size(hcbounds,1),2)+boundpad, EEG.pnts-1] ./ EEG.srate));
                                            end
                                            for s2 = size(hcbounds,1):-1:1
                                                if s2 == 1
                                                    if hcbounds(s2,1) <= boundpad
                                                        curexcise = [0, 0];
                                                    else
                                                        curexcise = [0, hcbounds(s2,1)-boundpad];
                                                    end
                                                else
                                                    curexcise = [hcbounds(s2-1,2)+boundpad, hcbounds(s2,1)-boundpad];
                                                end
                                                disp(['Excising data from ' num2str(curexcise(1)./ EEG.srate) ' to ' num2str(curexcise(2)./ EEG.srate) ' seconds...']);
                                                EEG = pop_select( EEG,'notime',(curexcise ./ EEG.srate) );
                                            end
                                        end
                                        
                                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                        % Run per-participant, per-band main module code
                                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                        if strcmp(module, 'Hypnogram')
                                            %Load pow structure to carry all needed vars to HYP_PlotHypnograms function
                                            pow = [];
                                            pow.pidstring = pidstring;
                                            pow.EEG = EEG;
                                            pow.hyp_raster = hyp_raster;
                                            pow.hyp_shown4 = hyp_shown4;
                                            pow.eegrootfolder = app.eegrootfolder;
                                            pow.imgmode = imgmode;
                                            
                                            HYP_PlotHypnograms(pow);
                                            
                                        elseif ~isempty(strfind(lower(module), 'spectra'))
                                            if strcmp(module, 'Spectra (newtimef)')
                                                switch app.ExperimentnameDropDown.Value
                                                    case 'EmoPhys'
                                                        EEG = pop_select( EEG,'channel',{'F3' 'F4'});
                                                        EEG = pop_eegchanoperator( EEG, {  'ch3 = (ch1 + ch2) / 2 label F3F4AVG'} ,...
                                                            'ErrorMsg', 'popup', 'Warning', 'on' ); % GUI: 25-Jan-2019 10:21:49
                                                        %figure;
                                                        [curersp{sub}, ~, ~, curtimes{sub}, curfreqs{sub}] = newtimef(EEG.data(3,:), EEG.pnts,...
                                                            [EEG.xmin EEG.xmax].*1000, EEG.srate, [3 0.5],...
                                                            'baseline', [NaN], 'freqs', [0.1 25], 'nfreqs', 2500, 'plotitc' , 'off',...
                                                            'plotphase', 'off', 'ntimesout', 50, 'padratio', 1);
                                                        %figure; plot(curfreqs{sub}, mean(curersp{sub}, 2))
                                                    otherwise
                                                        disp(['Method ' module ' is not defined for experiment ' app.ExperimentnameDropDown.Value '. See Ahren to set up this processing stream.']);
                                                end
                                            else
                                                %%%Run preprocessing code common to EEGLAB and Fieldtrip Spectra methods
                                                %Subset by time
                                                if strcmp(spectime, 'all')
                                                    timelbms = 0;
                                                    timeubms = EEG.pnts*(1000/EEG.srate);
                                                else
                                                    timelbms = (spectime(1) * 60 * 1000);
                                                    timeubms = (spectime(2) * 60 * 1000);
                                                end
                                                
                                                %Optionally apply 60 Hz notch filter
                                                if spec60filt == 1
                                                    EEG  = pop_basicfilter( EEG,  1:EEG.nbchan , 'Boundary', 'boundary', 'Cutoff',  60,...
                                                        'Design', 'notch', 'Filter', 'PMnotch', 'Order',  180, 'RemoveDC', 'on' ); % GUI: 22-Apr-2019 09:44:39
                                                    specfiltflag = '60HzNotch_';
                                                else
                                                    specfiltflag = '';
                                                end
                                                
                                                %Condense ROIs into a string for titles and filenames
                                                if isnumeric(spectime)
                                                    roistring = [regexprep(num2str(spectime), ' .', '-') 'min'];
                                                else
                                                    roistring = [spectime{1} 'min'];
                                                end
                                                roistring = [specfiltflag bcimode 'BCI_' strjoin(strrep(keepstages,'Stage-', ''), '-') '_' roistring];
                                                
                                                %Choose channels for averaged spectra plot
                                                if ~exist('specchans')
                                                    specchans = listdlg('PromptString', 'Calculate avg spectra over which channels?', 'SelectionMode', 'multiple',...
                                                        'ListString', {EEG.chanlocs.labels}, 'Name', 'Avg spectra chans', 'ListSize', [300 300]);
                                                    specchans = {EEG.chanlocs(specchans).labels};
                                                end
                                                
                                                if strcmp(module, 'Spectra (EEGLAB)')
                                                    %Load pow structure to carry all needed vars to SEL_GenerateSpectra function
                                                    pow = [];
                                                    pow.pid = pid;
                                                    pow.EEG = EEG;
                                                    pow.timelbms = timelbms;
                                                    pow.timeubms = timeubms;
                                                    pow.specchans = specchans;
                                                    pow.spectime = spectime;
                                                    pow.specfreqs = specfreqs;
                                                    pow.spec60filt = spec60filt;
                                                    pow.spectimestg = spectimestg;
                                                    pow.procstring = procstring;
                                                    pow.pidbcichans = pidbcichans;
                                                    pow.roistring = roistring;
                                                    pow.eegrootfolder = app.eegrootfolder;
                                                    pow.bcimode = bcimode;
                                                    pow.setfp = setfp;
                                                    pow.imgmode = imgmode;
                                                    if exist('ss_group')
                                                        pow.ss_group = ss_group;
                                                    end
                                                    
                                                    [ss_group, ss_freqs, ssfigpath] = SEL_GenerateSpectra(pow);
                                                    
                                                elseif strcmp(module, 'Spectra (FieldTrip)')
                                                    %Load pow structure to carry all needed vars to SFT_GenerateSpectra function
                                                    pow = [];
                                                    pow.pid = pid;
                                                    pow.EEG = EEG;
                                                    pow.timelbms = timelbms;
                                                    pow.timeubms = timeubms;
                                                    pow.specchans = specchans;
                                                    pow.specfreqs = specfreqs;
                                                    pow.spectime = spectime;
                                                    pow.spec60filt = spec60filt;
                                                    pow.spectimestg = spectimestg;
                                                    pow.fts_winlength = fts_winlength;
                                                    pow.fts_winoverlap = fts_winoverlap;
                                                    pow.ftspecplotstyle = ftspecplotstyle;
                                                    pow.roistring = roistring;
                                                    pow.eegrootfolder = app.eegrootfolder;
                                                    pow.bcimode = bcimode;
                                                    pow.pidbcichans = pidbcichans;
                                                    pow.setfp = setfp;
                                                    pow.procstring = procstring;
                                                    pow.imgmode = imgmode;
                                                    if exist('fts_group')
                                                        pow.fts_group = fts_group;
                                                    end
                                                    
                                                    [fts_group, ftsfigpath] = SFT_GenerateSpectra(pow);
                                                    
                                                end
                                            end
                                        elseif strcmp(module, 'Power (Hilbert)')
                                            %Load pow structure to carry all needed vars to PWH_GenerateEnvelopes function
                                            pow = [];
                                            pow.pid = pid;
                                            pow.ALLEEG = ALLEEG;
                                            pow.EEG = EEG;
                                            pow.eegfn = eegfn;
                                            pow.eegfp = eegfp;
                                            pow.setfp = setfp;
                                            pow.makeenvelopes = makeenvelopes;
                                            pow.dohilblp = dohilblp;
                                            pow.thetamode = thetamode;
                                            pow.thetamodesuf = thetamodesuf;
                                            pow.procstring = procstring;
                                            pow.bands = bands;
                                            pow.b = b;
                                            pow.bandfreqs = bandfreqs;
                                            pow.filtdesign = filtdesign;
                                            pow.filtorder = filtorder;
                                            pow.remdc = remdc;
                                            pow.arthresh = arthresh;
                                            
                                            PWH_GenerateEnvelopes(pow, app);
                                            
                                        elseif strcmp(module, 'Power (newtimef)')
                                            %Load pow structure to carry all needed vars to PWN_CalcPower function
                                            pow = [];
                                            pow.pid = pid; %9
                                            pow.EEG = EEG; %6
                                            pow.tags = tags;
                                            pow.fullbw = fullbw; %7
                                            pow.eegfn = eegfn; %5
                                            pow.eegfp = eegfp; %1
                                            pow.ntfnfreqs = ntfnfreqs; %2
                                            pow.ntftbs = ntftbs; %16
                                            pow.bands = bands; %3
                                            pow.b = b; %4
                                            pow.bandfreqs = bandfreqs; %12
                                            pow.arthresh = arthresh; %11
                                            pow.perfar = perfar; %13
                                            pow.otic = otic; %14
                                            pow.ptic = ptic; %15
                                            pow.imgmode = imgmode;
                                            if exist('tfo')
                                                pow.tfo = tfo; %8
                                            end
                                            if exist('ntfplotchans')
                                                pow.ntfplotchans = ntfplotchans; %10
                                            end
                                            if exist('errs')
                                                pow.errs = errs; %17
                                            end
                                            
                                            [tfo, ntfplotchans, errs] = PWN_CalcPower(pow, app);
                                            
                                        elseif strcmp(module, 'TMRtistry (WIP)')
                                            if strcmp(app.ExperimentnameDropDown.Value, 'Cuing')
                                                if length(TMRtistry_Script) > 1
                                                    eval(TMRtistry_Script)
                                                else
                                                    msgbox('Cuing TMRtistry processing settings were not found. Please use the file menu "Import... --> TMRtistry Script" to select the text file to be implemented (i.e. Cuing_TMRtistry_Script.txt)')
                                                    warning('Cuing TMRtistry processing settings were not found. Please use the file menu "Import... --> TMRtistry Script" to select the text file to be implemented (i.e. Cuing_TMRtistry_Script.txt)')
                                                end
                                                % Cuing-experiment specific code (Cuing_TMRtistry_Script contents) used to live here. -ABF 2022-01-12
                                            else
                                                if length(TMRtistry_Script) > 1
                                                    eval(TMRtistry_Script)
                                                else
                                                    msgbox('TMRtistry processing settings were not found. Please use the file menu "Import... --> TMRtistry Script" to select the text file to be implemented (e.g. **_TMRtistry_Script.txt)')
                                                    warning('TMRtistry processing settings were not found. Please use the file menu "Import... --> TMRtistry Script" to select the text file to be implemented (e.g. **_TMRtistry_Script.txt)')
                                                end
                                            end
                                        end
                                    else
                                        app.AnalyzeButton.Text = ['None of the requested ' thetamodesuf bands{b} ' sleep stages (' strjoin(keepstages, ', ') ') found for ' pid '! Moving on to next frequency band...'];
                                    end %End "if hypnochanges > 0" conditional
                                    
                                catch bnderr
                                    errs = [errs bnderr];
                                    
                                end %End try/catch loop
                                
                            end %End loop over bands
                            
                        elseif strcmp(module, 'Spindle Detection (Ferrarelli)')
                            disp('Starting spindle detection module based on Ferrarelli et al. 2007 Am J Psychiatry (https://doi.org/10.1176/ajp.2007.164.3.483) and McClain et al. 2016 Neural Plast (https://doi.org/10.1155/2016/3670951). Please cite appropriately!');
                            
                            %Load pow briefcase structure to carry all needed vars to module
                            pow = [];
                            pow.EEG = EEG;
                            pow.lower_thresh_ratio = lower_thresh_ratio;
                            pow.upper_thresh_ratio = upper_thresh_ratio;
                            pow.doresampling = doresampling;
                            pow.tags = tags;
                            pow.knowntags = knowntags;
                            pow.eegfn = eegfn;
                            pow.eegfp = eegfp;
                            pow.bldrlowpass = bldrlowpass; %Is including this option advisable? Or should it be nixed?
                            pow.visualizedata = visualizedata;
                            pow.imgmode = imgmode;
                            
                            SPND_Ferrarelli(pow);
                            
                        elseif strcmp(module, 'PAC (Muehlroth)')
                            %Add code here...
                            disp('Starting WIP PAC algorithm based on Muehlroth (2019)...');
                            
                            %%%Build a briefcase...
                            pow = [];
                            pow.EEG = EEG;
                            pow.eegfp = eegfp;
                            pow.pid = pid;
                            
                            %%%Send it out...
                            PAC_A_PSG_preprocessing(pow);
                        end
                    end %End of loop over participants
                elseif strcmp(module, 'Envelope Viewer') %fka _penvviewer.m
                    
                    %Load briefcase for ENVW_CollateOrLoadEnvelopes
                    pow = [];
                    pow.eegfns = app.eegfiles;
                    pow.eegrootfolder = app.eegrootfolder;
                    pow.expname = app.ExperimentnameDropDown.Value;
                    pow.collatenew = collatenew;
                    pow.bands = bands;
                    pow.thetamode = thetamode;
                    pow.avgacross = avgacross;
                    pow.truemeasure = truemeasure;
                    pow.knowntags = knowntags;
                    pow.bandstgs = bandstgs;
                    pow.temporalroi = temporalroi;
                    if exist('chans') == 1
                        pow.chans = chans;
                    end
                    if exist('nightkey') == 1
                        pow.nightkey = nightkey;
                    end
                    
                    %Run ENVW_CollateOrLoadEnvelopes
                    tic;
                    [toview, chans, thetamodesuf, bandnums, envoutsuffix] = ENVW_CollateOrLoadEnvelopes(pow);
                    toc
                    
                elseif strcmp(module, 'Spectrogram Viewer') %fka CZ2000fr_tfadder.m & TFRstatistics.m
                    disp('Add Spectrogram Viewer code here...');
                    
                    %Load pow briefcase structure to carry all needed vars to module
                    pow = [];
                    pow.curexpname = app.ExperimentnameDropDown.Value;
                    pow.edfdir = app.eegrootfolder;
                    pow.eegfiles = app.eegfiles;
                    pow.stgfiles = app.stgfiles;
                    pow.stgmethod = stgmethod;
                    pow.thinkfs = thinkfs;
                    pow.extfp = extfp;
                    pow.knowntags = knowntags;
                    pow.data_nfreqs = data_nfreqs;
                    pow.data_elec = data_elec;
                    pow.data_band = data_band;
                    pow.savetifs = savetifs;
                    pow.savetifmetas = savetifmetas;
                    
                    pow = SpectrogramViewer(pow); %This function still WIP... - ABF 2022-02-14
                    
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Group/grand average processing and plotting
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if strcmp(module, 'Spectra (EEGLAB)')
                    %Load pow structure to carry all needed vars to SEL_PlotGroupAverages function
                    pow = [];
                    pow.ssfigpath = ssfigpath;
                    pow.roistring = roistring;
                    pow.ss_freqs = ss_freqs;
                    pow.ss_group = ss_group;
                    pow.pidbcichans = pidbcichans;
                    pow.rerefmode = rerefmode;
                    pow.specchans = specchans;
                    pow.specfreqs = specfreqs;
                    
                    SEL_PlotGroupAverages(pow);
                    
                elseif strcmp(module, 'Spectra (FieldTrip)')
                    %Load pow structure to carry all needed vars to SFT_PlotGroupAverages function
                    pow = [];
                    pow.ftsfigpath = ftsfigpath;
                    pow.fts_group = fts_group;
                    pow.roistring = roistring;
                    pow.specchans = specchans;
                    pow.dipfitstr = dipfitstr;
                    pow.ftspecplotstyle = ftspecplotstyle;
                    pow.imgmode = imgmode;
                    
                    SFT_PlotGroupAverages(pow);
                    
                elseif strcmp(module, 'Spectra (newtimef)')
                    if strcmp(expname, 'EmoPhys')
                        specs = cellfun(@(x) mean(x,2), curersp, 'UniformOutput', 0);
                        for s = 1:length(curersp)
                            figure; plot(curfreqs{s}, specs{s}); title([app.eegfiles{s} fullbandstages]);
                            %print(gcf, '-dpdf', [eegfp 'Output' filesep 'spectra' filesep strtok(app.eegfiles{s},'.') '_spec.pdf']);
                        end
                        specsmat = cell2mat(specs)';
                        gavspec = mean(specsmat,1);
                        figure; plot(curfreqs{1}, gavspec); title(['GAVspectra_' datestr(now, 'yyyy-mm-dd') fullbandstages]);
                        print(gcf, '-dpng', [eegfp 'Output' filesep 'spectra' filesep 'GAV_spec.png']);
                    end
                    
                elseif strcmp(module, 'Envelope Viewer')
                    
                    %Need to ensure 'distinguishable_colors' is a function on the path. - ABF 2022-03-07
                    
                    %Load briefcase for ENVW_PlotAndQuantify
                    pow = [];
                    pow.eegrootfolder = app.eegrootfolder;
                    pow.expname = app.ExperimentnameDropDown.Value;
                    pow.toview = toview;
                    pow.chans = chans;
                    pow.srate_guess = srate;
                    pow.bands = bands;
                    pow.bandnums = bandnums;
                    pow.thetamodesuf = thetamodesuf;
                    pow.avgacross = avgacross;
                    pow.truemeasure = truemeasure;
                    pow.omitempties = omitempties;
                    pow.archoice = archoice;
                    pow.clnmeth = clnmeth;
                    pow.medsmoothwind = medsmoothwind;
                    pow.blchoice = blchoice;
                    pow.blremwind = blremwind;
                    pow.gblchoice = gblchoice;
                    pow.statsmethod = statsmethod;
                    pow.stagelist = stagelist;
                    pow.envoutsuffix = envoutsuffix;
                    pow.dipfitstr = dipfitstr;
                    
                    %Run ENVW_PlotAndQuantify
                    tic;
                    ENVW_PlotAndQuantify(pow);
                    toc
                    
                elseif strcmp(module, 'PAC (Muehlroth)')
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %PAC (Muehlroth) code that runs over all participants
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    %Add code that works on the full dataset here - ABF 2022-03-02
                end
                
                isanalyze = 0;
                diary off;
                
                app.AnalyzeButton.FontSize = 12;
                app.AnalyzeButton.Text = 'Analyze';
                figure(app.UIFigure); % Refocus GUI as main window
                
            catch anbutton_exc
                isanalyze = 0;
                disp(getReport(anbutton_exc))
                warning(['Analyze button process crashed at ' datestr(now) ', closing diary log...']);
                diary off;
                
                app.AnalyzeButton.FontSize = 12;
                app.AnalyzeButton.Text = 'Analyze';
                figure(app.UIFigure); % Refocus GUI as main window
            end
        end

        % Button pushed function: ARBCISettingsButton
        function ARBCISettingsButtonPush(app, event)
            app.arbci_settings = '';
            
            if ismac == 0
                [filename, path] = uigetfile('title', 'Select file containing Bad Channel Interpolation settings','*_ARBCI_Settings.txt');
            else
                [filename, path] = uigetfile('*.*', 'Select *_ARBCI_Settings.txt file containing Bad Channel Interpolation settings');
            end
            
            if filename ~= 0
                app.arbci_settings = fileread(strcat(path, filename));
            end
            
            if ~isempty(app.arbci_settings)
                app.ARBCISettingsLamp.Color = 'g';
            else
                app.ARBCISettingsLamp.Color = 'r';
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
            
        end

        % Callback function
        function TMRtistrySettingsButtonPush(app, event)
            app.tmrtistry_settings = '';
            
            if ismac == 0
                [filename, path] = uigetfile('title', 'Select file containing TMRtistry settings','*_TMRtistry_Settings.txt');
            else
                [filename, path] = uigetfile('*.*', 'Select *_TMRtistry_Settings.txt file containing TMRtistry settings');
            end
            
            if filename ~= 0
                app.tmrtistry_settings = fileread(strcat(path, filename));
            end
            
            if ~isempty(app.tmrtistry_settings)
                app.TMRtistrySettingsLamp.Color = 'g';
            else
                app.TMRtistrySettingsLamp.Color = 'r';
            end
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Button pushed function: PreprocessingSettingsButton
        function PreprocessingSettingsButtonPush(app, event)
            app.preproc_settings = '';
            
            if ismac == 0
                [filename, path] = uigetfile('title', 'Select file containing custom preprocessing code','*_Preproc_Settings.txt');
            else
                [filename, path] = uigetfile('*.*', 'Select *_Preproc_Settings.txt file containing custom preprocessing code');
            end
            
            if filename ~= 0
                app.preproc_settings = fileread(strcat(path, filename));
            end
            
            if ~isempty(app.preproc_settings)
                app.PreProcessingSettingsLamp.Color = 'g';
            else
                app.PreProcessingSettingsLamp.Color = 'r';
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Callback function
        function TMRtistryScriptButtonPush(app, event)
            app.tmrtistry_script = '';
            
            if ismac == 0
                [filename, path] = uigetfile('title', 'Select file containing script to run TMRtistry','*_TMRtistry_Script.txt');
            else
                [filename, path] = uigetfile('*.*', 'Select *_TMRtistry_Script.txt file containing script to run TMRtistry');
            end
            
            if filename ~= 0
                app.tmrtistry_script = fileread(strcat(path, filename));
            end
            
            if ~isempty(app.tmrtistry_script)
                app.TMRtistryCustomScriptLamp.Color = 'g';
            else
                app.TMRtistryCustomScriptLamp.Color = 'r';
            end
            
            figure(app.UIFigure); % Refocus GUI as main window
        end

        % Value changed function: ArtifactChoiceDropdown
        function ArtifactChoiceDropdownValueChanged(app, event)
            value = app.ArtifactChoiceDropdown.Value;
            
            if strcmp(value, 'smoothed')
                app.MedianSmoothWindowNumericEditField.Visible = 'on';
                app.MedianSmoothWindowNumericEditFieldLabel.Visible = 'on';
            else
                app.MedianSmoothWindowNumericEditField.Visible = 'off';
                app.MedianSmoothWindowNumericEditFieldLabel.Visible = 'off';
            end
        end

        % Selection changed function: 
        % CollateorloadenvelopesButtonGroup
        function CollateorloadenvelopesButtonGroupSelectionChanged(app, event)
            
            AverageenvelopesacrossButtonGroupSelectionChanged(app, event)
            
        end

        % Selection changed function: 
        % AverageenvelopesacrossButtonGroup
        function AverageenvelopesacrossButtonGroupSelectionChanged(app, event)
            
            if strcmp(app.CollateorloadenvelopesButtonGroup.SelectedObject.Text, 'Collate')
                if strcmp(app.AverageenvelopesacrossButtonGroup.SelectedObject.Text, 'chans')
                    app = setStep3Bands(app, {'delta', 'theta', 'sigma'}, 'othersOn', 1, 1, 1, 0, 0, 0);
                    
                    app.EnvViewerTempROILowNumericEditField.Visible = 'off';
                    app.EnvViewerTempROILowNumericEditFieldLabel.Visible = 'off';
                    app.EnvViewerTempROIHighNumericEditField.Visible = 'off';
                    app.EnvViewerTempROIHighNumericEditFieldLabel.Visible = 'off';
                elseif strcmp(app.AverageenvelopesacrossButtonGroup.SelectedObject.Text, 'time')
                    app = setStep3Bands(app, {'delta', 'theta', 'sigma'}, 'othersOn', 1, 2, 1, 0, 1, 0);
                    
                    app.DeltaCheckBox.Value = 1; app.ThetaCheckBox.Value = 1; app.SigmaCheckBox.Value = 1;
                    app.DeltaN2.Value = 1; app.ThetaN2.Value = 1; app.SigmaN2.Value = 1;
                    app.DeltaN3.Value = 1; app.ThetaN3.Value = 1; app.SigmaN3.Value = 1;
                    
                    app.AllBandsButton.Enable = 'on';
                    app.AllStagesButton.Enable = 'on';
                    
                    app.EnvViewerTempROILowNumericEditField.Visible = 'on';
                    app.EnvViewerTempROILowNumericEditFieldLabel.Visible = 'on';
                    app.EnvViewerTempROIHighNumericEditField.Visible = 'on';
                    app.EnvViewerTempROIHighNumericEditFieldLabel.Visible = 'on';
                end
                
            else %Load
                app = setStep3Bands(app, {''}, 'othersOff', 1, 1, 1, 1, 1, 1);
                
                app.EnvViewerTempROILowNumericEditField.Visible = 'off';
                app.EnvViewerTempROILowNumericEditFieldLabel.Visible = 'off';
                app.EnvViewerTempROIHighNumericEditField.Visible = 'off';
                app.EnvViewerTempROIHighNumericEditFieldLabel.Visible = 'off';
            end
            
            if strcmp(app.CollateorloadenvelopesButtonGroup.SelectedObject.Text, 'Collate') && app.ThetaCheckBox.Value == 1
                app.ThetaFilterModeDropDown_EnvViewer.Visible = 'on';
                app.ThetaFilterModeDropDown_EnvViewerLabel.Visible = 'on';
            else
                app.ThetaFilterModeDropDown_EnvViewer.Visible = 'off';
                app.ThetaFilterModeDropDown_EnvViewerLabel.Visible = 'off';
                app.ThetaFilterModeDropDown_EnvViewer.Value = 'none';
            end
            
        end

        % Value changed function: BaselineRemovalChoiceCheckBox
        function BaselineRemovalChoiceCheckBoxValueChanged(app, event)
            value = app.BaselineRemovalChoiceCheckBox.Value;
            
            if value == 1
                app.BaselineRemovalWindowLengthNumericEditField.Visible = 'on';
                app.HowlongofawindowshouldbeusedforlocalbaselinesecLabel.Visible = 'on';
            else
                app.BaselineRemovalWindowLengthNumericEditField.Visible = 'off';
                app.HowlongofawindowshouldbeusedforlocalbaselinesecLabel.Visible = 'off';
                app.BaselineRemovalWindowLengthNumericEditField.Value = 0;
            end
            
        end

        % Value changed function: CustomTimeIntervalEnd, 
        % CustomTimeIntervalEnd_FT, CustomTimeIntervalStart, 
        % CustomTimeIntervalStart_FT
        function SpectimeInputValueChanged(app, event)
            switch app.AnalysisModuleselectoneDropDown.Value
                case 'Spectra (EEGLAB)'
                    app.TimeintervalforspectraButtonGroup.SelectedObject = app.CustomtimerangeminStartButton;
                case 'Spectra (FieldTrip)'
                    app.TimeintervalforspectraButtonGroup_FT.SelectedObject = app.CustomtimerangeminStartButton_FT;
                case 'Spectra (newtimef)'
                    app.TimeintervalforspectraButtonGroup_ntf.SelectedObject = app.CustomtimerangeminStartButton_ntf;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.PSGpowerLabel = uilabel(app.UIFigure);
            app.PSGpowerLabel.Text = 'PSGpower';
            app.PSGpowerVersionLabel = uilabel(app.UIFigure);
            app.PSGpowerVersionLabel.Text = 'v1.0.0';
            app.Step1SelectfiledirectoryPanel = uipanel(app.UIFigure);
            app.Step1SelectfiledirectoryPanel.Title = 'Step 1: Select file directory';
            app.FileSelectListbox = uilistbox(app.Step1SelectfiledirectoryPanel);
            app.FileSelectListbox.Items = {'Selected files will show up here'};
            app.FileSelectListbox.Multiselect = 'on';
            app.FileSelectListbox.Enable = 'off';
            app.FileSelectListbox.Value = {'Selected files will show up here'};
            app.AddDirectoryforAnalysisButton = uibutton(app.Step1SelectfiledirectoryPanel, 'push');
            app.AddDirectoryforAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AddDirectoryforAnalysisButtonPushed, true);
            app.AddDirectoryforAnalysisButton.Text = 'Add Directory for Analysis';
            app.IncludesubdirectoriesCheckBox = uicheckbox(app.Step1SelectfiledirectoryPanel);
            app.IncludesubdirectoriesCheckBox.Text = 'Include subdirectories';
            app.SelectfilestobeanalyzedwhileholdingCtrlthenclickLabel = uilabel(app.Step1SelectfiledirectoryPanel);
            app.SelectfilestobeanalyzedwhileholdingCtrlthenclickLabel.Text = 'Select files to be analyzed while holding Ctrl, then click ==>';
            app.ChooseSubsetButton = uibutton(app.Step1SelectfiledirectoryPanel, 'state');
            app.ChooseSubsetButton.ValueChangedFcn = createCallbackFcn(app, @ChooseSubsetButtonValueChanged, true);
            app.ChooseSubsetButton.Text = 'Choose Subset';
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitchLabel = uilabel(app.Step1SelectfiledirectoryPanel);
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitchLabel.Text = {'Session info .csv found. '; 'Use to determine montage and scoring?'};
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch = uiswitch(app.Step1SelectfiledirectoryPanel, 'slider');
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Items = {'Yes', 'No'};
            app.SessioninfocsvfoundUsetodeterminemontageandscoringSwitch.Value = 'No';
            app.UpdateFileListButton = uibutton(app.Step1SelectfiledirectoryPanel, 'push');
            app.UpdateFileListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFileListButtonPushed, true);
            app.UpdateFileListButton.Text = 'Update File List';
            app.DirectoryConfirmationLabel = uilabel(app.Step1SelectfiledirectoryPanel);
            app.DirectoryConfirmationLabel.Text = 'Directory added. All files shown in the box below (after choosing staging software) will be analyzed.';
            app.WhatsoftwarewasusedforsleepstagenotationDropDownLabel = uilabel(app.Step1SelectfiledirectoryPanel);
            app.WhatsoftwarewasusedforsleepstagenotationDropDownLabel.Text = 'What software was used for sleep stage notation?';
            app.WhatsoftwarewasusedforsleepstagenotationDropDown = uidropdown(app.Step1SelectfiledirectoryPanel);
            app.WhatsoftwarewasusedforsleepstagenotationDropDown.Items = {' ', 'SleepSMG/Hume', 'TWin', 'REMlogic', 'None (Experimental)'};
            app.WhatsoftwarewasusedforsleepstagenotationDropDown.ValueChangedFcn = createCallbackFcn(app, @WhatsoftwarewasusedforsleepstagenotationDropDownValueChanged, true);
            app.WhatsoftwarewasusedforsleepstagenotationDropDown.Value = ' ';
            app.EEGSamplingFrequencyHzEditFieldLabel = uilabel(app.Step1SelectfiledirectoryPanel);
            app.EEGSamplingFrequencyHzEditFieldLabel.Text = {'EEG Sampling'; 'Frequency (Hz):'};
            app.EEGSamplingFrequencyHzEditField = uieditfield(app.Step1SelectfiledirectoryPanel, 'text');
            app.EEGSamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @EEGSamplingFrequencyHzEditFieldValueChanged, true);
            app.EEGSamplingFrequencyHzEditField.Value = '0';
            app.Step2SelectsignalprocessingapproachPanel = uipanel(app.UIFigure);
            app.Step2SelectsignalprocessingapproachPanel.Title = 'Step 2: Select signal processing approach';
            app.TabGroup = uitabgroup(app.Step2SelectsignalprocessingapproachPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HypnogramTab = uitab(app.TabGroup);
            app.HypnogramTab.Title = 'Hypnogram';
            app.HypnogramRasterPlotCheckbox = uicheckbox(app.HypnogramTab);
            app.HypnogramRasterPlotCheckbox.Text = 'Include raster plot of sleep stages?';
            app.HypnogramRasterPlotCheckbox.Value = true;
            app.SpectraEEGLABTab = uitab(app.TabGroup);
            app.SpectraEEGLABTab.Title = 'Spectra (EEGLAB)';
            app.Apply60HznotchfilterbeforegeneratingspectraCheckBox = uicheckbox(app.SpectraEEGLABTab);
            app.Apply60HznotchfilterbeforegeneratingspectraCheckBox.Text = 'Apply 60 Hz notch filter before generating spectra?';
            app.FrequencyrangeofinterestHzLabel_2 = uilabel(app.SpectraEEGLABTab);
            app.FrequencyrangeofinterestHzLabel_2.Text = 'Frequency range of interest (Hz):';
            app.PlotFreqROIHzMinimumEditField = uieditfield(app.SpectraEEGLABTab, 'numeric');
            app.PlotFreqROIHzMinimumEditField.Limits = [0 Inf];
            app.toEditFieldLabel = uilabel(app.SpectraEEGLABTab);
            app.toEditFieldLabel.Text = 'to';
            app.PlotFreqROIHzMaximumEditField = uieditfield(app.SpectraEEGLABTab, 'numeric');
            app.PlotFreqROIHzMaximumEditField.Limits = [0 Inf];
            app.PlotFreqROIHzMaximumEditField.Value = 25;
            app.TimeintervalforspectraButtonGroup = uibuttongroup(app.SpectraEEGLABTab);
            app.TimeintervalforspectraButtonGroup.Title = 'Time interval for spectra';
            app.UsealldatainselectedstagesButton = uiradiobutton(app.TimeintervalforspectraButtonGroup);
            app.UsealldatainselectedstagesButton.Text = 'Use all data in selected stages';
            app.UsealldatainselectedstagesButton.Value = true;
            app.CustomtimerangeminStartButton = uiradiobutton(app.TimeintervalforspectraButtonGroup);
            app.CustomtimerangeminStartButton.Text = 'Custom time range (min)     Start';
            app.CustomTimeIntervalStart = uieditfield(app.TimeintervalforspectraButtonGroup, 'numeric');
            app.CustomTimeIntervalStart.Limits = [0 Inf];
            app.CustomTimeIntervalStart.RoundFractionalValues = 'on';
            app.CustomTimeIntervalStart.ValueChangedFcn = createCallbackFcn(app, @SpectimeInputValueChanged, true);
            app.CustomTimeIntervalEnd = uieditfield(app.TimeintervalforspectraButtonGroup, 'numeric');
            app.CustomTimeIntervalEnd.Limits = [0 Inf];
            app.CustomTimeIntervalEnd.RoundFractionalValues = 'on';
            app.CustomTimeIntervalEnd.ValueChangedFcn = createCallbackFcn(app, @SpectimeInputValueChanged, true);
            app.EndLabel = uilabel(app.TimeintervalforspectraButtonGroup);
            app.EndLabel.Text = 'End';
            app.Label_6 = uilabel(app.SpectraEEGLABTab);
            app.Label_6.Text = {'Note: Data will be subset to the stages chosen '; 'in Step 3 before being truncated to X min.'};
            app.SpectraFieldTripTab = uitab(app.TabGroup);
            app.SpectraFieldTripTab.Title = 'Spectra (FieldTrip)';
            app.SpectraplotunitsDropDownLabel = uilabel(app.SpectraFieldTripTab);
            app.SpectraplotunitsDropDownLabel.Text = 'Spectra plot units:';
            app.SpectraplotunitsDropDown = uidropdown(app.SpectraFieldTripTab);
            app.SpectraplotunitsDropDown.Items = {'logpower', 'eeglab', 'abspower'};
            app.SpectraplotunitsDropDown.Value = 'logpower';
            app.TimeintervalforspectraButtonGroup_FT = uibuttongroup(app.SpectraFieldTripTab);
            app.TimeintervalforspectraButtonGroup_FT.Title = 'Time interval for spectra';
            app.UsealldatainselectedstagesButton_FT = uiradiobutton(app.TimeintervalforspectraButtonGroup_FT);
            app.UsealldatainselectedstagesButton_FT.Text = 'Use all data in selected stages';
            app.UsealldatainselectedstagesButton_FT.Value = true;
            app.CustomtimerangeminStartButton_FT = uiradiobutton(app.TimeintervalforspectraButtonGroup_FT);
            app.CustomtimerangeminStartButton_FT.Text = 'Custom time range (min)     Start';
            app.CustomTimeIntervalStart_FT = uieditfield(app.TimeintervalforspectraButtonGroup_FT, 'numeric');
            app.CustomTimeIntervalStart_FT.Limits = [0 Inf];
            app.CustomTimeIntervalStart_FT.RoundFractionalValues = 'on';
            app.CustomTimeIntervalStart_FT.ValueChangedFcn = createCallbackFcn(app, @SpectimeInputValueChanged, true);
            app.CustomTimeIntervalEnd_FT = uieditfield(app.TimeintervalforspectraButtonGroup_FT, 'numeric');
            app.CustomTimeIntervalEnd_FT.Limits = [0 Inf];
            app.CustomTimeIntervalEnd_FT.RoundFractionalValues = 'on';
            app.CustomTimeIntervalEnd_FT.ValueChangedFcn = createCallbackFcn(app, @SpectimeInputValueChanged, true);
            app.EndLabel_FT = uilabel(app.TimeintervalforspectraButtonGroup_FT);
            app.EndLabel_FT.Text = 'End';
            app.FrequencyrangeofinterestHzLabel = uilabel(app.SpectraFieldTripTab);
            app.FrequencyrangeofinterestHzLabel.Text = 'Frequency range of interest (Hz):';
            app.PlotFreqROIMinimumEditField_FT = uieditfield(app.SpectraFieldTripTab, 'numeric');
            app.PlotFreqROIMinimumEditField_FT.Limits = [0 Inf];
            app.toEditField_2Label_2 = uilabel(app.SpectraFieldTripTab);
            app.toEditField_2Label_2.Text = 'to';
            app.PlotFreqROIMaximumEditField_FT = uieditfield(app.SpectraFieldTripTab, 'numeric');
            app.PlotFreqROIMaximumEditField_FT.Limits = [0 Inf];
            app.PlotFreqROIMaximumEditField_FT.Value = 25;
            app.Apply60HznotchfilterbeforegeneratingspectraCheckBox_FT = uicheckbox(app.SpectraFieldTripTab);
            app.Apply60HznotchfilterbeforegeneratingspectraCheckBox_FT.Text = 'Apply 60 Hz notch filter before generating spectra?';
            app.WindowlengthsecEditFieldLabel = uilabel(app.SpectraFieldTripTab);
            app.WindowlengthsecEditFieldLabel.Text = 'Window length (sec)';
            app.WindowlengthsecEditField_FT = uieditfield(app.SpectraFieldTripTab, 'numeric');
            app.WindowlengthsecEditField_FT.Value = 10;
            app.WindowoverlapproportionEditFieldLabel_2 = uilabel(app.SpectraFieldTripTab);
            app.WindowoverlapproportionEditFieldLabel_2.Text = 'Window overlap (proportion)';
            app.WindowoverlapproportionEditField_FT = uieditfield(app.SpectraFieldTripTab, 'numeric');
            app.WindowoverlapproportionEditField_FT.Limits = [0 1];
            app.WindowoverlapproportionEditField_FT.Value = 0.75;
            app.NoteLabelSpectra_FT = uilabel(app.SpectraFieldTripTab);
            app.NoteLabelSpectra_FT.Text = {'Note: Data will be subset to the stages chosen '; 'in Step 3 before being truncated to X min.'};
            app.PowerHilbertTab = uitab(app.TabGroup);
            app.PowerHilbertTab.Title = 'Power (Hilbert)';
            app.FiniteImpulseResponseFIRorderButtonGroup = uibuttongroup(app.PowerHilbertTab);
            app.FiniteImpulseResponseFIRorderButtonGroup.Title = 'Finite Impulse Response (FIR) order';
            app.HardcodedDefaultButton = uiradiobutton(app.FiniteImpulseResponseFIRorderButtonGroup);
            app.HardcodedDefaultButton.Text = 'Hardcoded Default';
            app.HardcodedDefaultButton.Value = true;
            app.CohensAlgorithmButton = uiradiobutton(app.FiniteImpulseResponseFIRorderButtonGroup);
            app.CohensAlgorithmButton.Text = 'Cohen''s Algorithm';
            app.ThetaFilterModeDropDownLabel = uilabel(app.PowerHilbertTab);
            app.ThetaFilterModeDropDownLabel.Text = 'Theta Filter Mode';
            app.ThetaFilterModeDropDown = uidropdown(app.PowerHilbertTab);
            app.ThetaFilterModeDropDown.Items = {'FIR', 'cheby1', 'cheby2', 'none'};
            app.ThetaFilterModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThetaFilterModeDropDownValueChanged, true);
            app.ThetaFilterModeDropDown.Value = 'cheby2';
            app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDownLabel = uilabel(app.PowerHilbertTab);
            app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDownLabel.Text = {'10 Hz Lowpass Amplitude'; 'Envelope (not usually recommended)'};
            app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown = uidropdown(app.PowerHilbertTab);
            app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown.Items = {'No lowpass', 'Lowpass'};
            app.HzLowpassAmplitudeEnvelopenotusuallyrecommendedDropDown.Value = 'No lowpass';
            app.HilbertEnvelopesButtonGroup = uibuttongroup(app.PowerHilbertTab);
            app.HilbertEnvelopesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HilbertEnvelopesButtonGroupSelectionChanged, true);
            app.HilbertEnvelopesButtonGroup.Title = 'Hilbert Envelopes';
            app.CreatenewenvelopefilesButton = uiradiobutton(app.HilbertEnvelopesButtonGroup);
            app.CreatenewenvelopefilesButton.Text = 'Create new envelope files';
            app.CreatenewenvelopefilesButton.Value = true;
            app.LoadexistingenvelopefilesButton = uiradiobutton(app.HilbertEnvelopesButtonGroup);
            app.LoadexistingenvelopefilesButton.Text = 'Load existing envelope files';
            app.PowernewtimefTab = uitab(app.TabGroup);
            app.PowernewtimefTab.Title = 'Power (newtimef)';
            app.RejectartifactsfirstCheckBox = uicheckbox(app.PowernewtimefTab);
            app.RejectartifactsfirstCheckBox.Text = {'Reject artifacts within frequency band of interest '; '(on a per-channel basis) before running newtimef?'};
            app.ofFrequencies5shortrecommended50mid999longEditFieldLabel = uilabel(app.PowernewtimefTab);
            app.ofFrequencies5shortrecommended50mid999longEditFieldLabel.Text = {'How many frequency steps to use in newtimef?'; '(long is 50, short is 5, 999 for padratio)'};
            app.ofFrequencies5shortrecommended50mid999longEditField = uieditfield(app.PowernewtimefTab, 'numeric');
            app.ofFrequencies5shortrecommended50mid999longEditField.Value = 5;
            app.WhatsizetemporalwindowshouldbeusedfornewtimefsecEditFieldLabel = uilabel(app.PowernewtimefTab);
            app.WhatsizetemporalwindowshouldbeusedfornewtimefsecEditFieldLabel.Text = 'What size temporal window should be used for newtimef (sec)?';
            app.WhatsizetemporalwindowshouldbeusedfornewtimefsecEditField = uieditfield(app.PowernewtimefTab, 'numeric');
            app.WhatsizetemporalwindowshouldbeusedfornewtimefsecEditField.Value = 5;
            app.SpindleDetectionFerrarelliTab = uitab(app.TabGroup);
            app.SpindleDetectionFerrarelliTab.Title = 'Spindle Detection (Ferrarelli)';
            app.Resampleto128HzCheckBox = uicheckbox(app.SpindleDetectionFerrarelliTab);
            app.Resampleto128HzCheckBox.Text = 'Should Ferrarelli method resample to 128 Hz?';
            app.Lowpasssigmaenvelopeat2HzCheckBox = uicheckbox(app.SpindleDetectionFerrarelliTab);
            app.Lowpasssigmaenvelopeat2HzCheckBox.Text = 'Should Ferrarelli method lowpass sigma "Envelope" at 2 Hz? ';
            app.DrawstagesofwaveformabstractionforFerrarellimethodCheckBox = uicheckbox(app.SpindleDetectionFerrarelliTab);
            app.DrawstagesofwaveformabstractionforFerrarellimethodCheckBox.Text = 'Draw stages of waveform abstraction for Ferrarelli method?';
            app.WhatlowerupperthresholdratiostouseLowerThresholdLabel = uilabel(app.SpindleDetectionFerrarelliTab);
            app.WhatlowerupperthresholdratiostouseLowerThresholdLabel.Text = 'What lower/upper threshold ratios to use?      Lower Threshold';
            app.FerrarelliThresholdLow = uieditfield(app.SpindleDetectionFerrarelliTab, 'numeric');
            app.FerrarelliThresholdLow.Value = 2;
            app.UpperThresholdLabel = uilabel(app.SpindleDetectionFerrarelliTab);
            app.UpperThresholdLabel.Text = 'Upper Threshold';
            app.FerrarelliThresholdHigh = uieditfield(app.SpindleDetectionFerrarelliTab, 'numeric');
            app.FerrarelliThresholdHigh.Value = 8;
            app.NoteFerrarelli2010uses210whileMcClainetalBoulderused26Label = uilabel(app.SpindleDetectionFerrarelliTab);
            app.NoteFerrarelli2010uses210whileMcClainetalBoulderused26Label.Text = {'Note: Ferrarelli,et al. 2007 used [2 8],'; 'while McClain et al. 2016 used [2 6] for the'; 'lower and upper thresholds..'};
            app.Label_2 = uilabel(app.SpindleDetectionFerrarelliTab);
            app.Label_2.Text = {'If you are not sure, do not do this.... an explanation of the consequences '; 'and potential risks can be found in the PSGpower manual.'};
            app.Label_3 = uilabel(app.SpindleDetectionFerrarelliTab);
            app.Label_3.Text = '(needed if you want to mirror McClain et al. approach)';
            app.Label_4 = uilabel(app.SpindleDetectionFerrarelliTab);
            app.Label_4.Text = {'FYI: The Ferrarelli method uses a minimum-order cheby2 filter '; 'with passband corners 11 and 15, and stopband corners 10 and 16.'};
            app.EnvelopeViewerTab = uitab(app.TabGroup);
            app.EnvelopeViewerTab.Title = 'Envelope Viewer';
            app.BaselineRemovalChoiceCheckBox = uicheckbox(app.EnvelopeViewerTab);
            app.BaselineRemovalChoiceCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineRemovalChoiceCheckBoxValueChanged, true);
            app.BaselineRemovalChoiceCheckBox.Text = {'Subtract a moving-window (local) baseline from'; 'amplitude envelopes?'};
            app.GlobalBaslineRemovalChoiceCheckBox = uicheckbox(app.EnvelopeViewerTab);
            app.GlobalBaslineRemovalChoiceCheckBox.Text = 'Subtract a global baseline from amplitude envelopes?';
            app.ArtifactNaNChoiceCheckBox = uicheckbox(app.EnvelopeViewerTab);
            app.ArtifactNaNChoiceCheckBox.Text = 'Mark artifactual samples as NaN?';
            app.HowlongofawindowshouldbeusedforlocalbaselinesecLabel = uilabel(app.EnvelopeViewerTab);
            app.HowlongofawindowshouldbeusedforlocalbaselinesecLabel.Text = {'How long of a window should'; 'be used for local baseline (sec)?'};
            app.BaselineRemovalWindowLengthNumericEditField = uieditfield(app.EnvelopeViewerTab, 'numeric');
            app.WhatartifacthandlingmethodshouldbeusedLabel = uilabel(app.EnvelopeViewerTab);
            app.WhatartifacthandlingmethodshouldbeusedLabel.Text = 'What artifact handling method should be used?';
            app.ArtifactChoiceDropdown = uidropdown(app.EnvelopeViewerTab);
            app.ArtifactChoiceDropdown.Items = {'none', 'smoothed', 'lowlp'};
            app.ArtifactChoiceDropdown.ValueChangedFcn = createCallbackFcn(app, @ArtifactChoiceDropdownValueChanged, true);
            app.ArtifactChoiceDropdown.Value = 'none';
            app.MedianSmoothWindowNumericEditFieldLabel = uilabel(app.EnvelopeViewerTab);
            app.MedianSmoothWindowNumericEditFieldLabel.Text = {'How long of a window should '; 'be used for median smoothing '; '(default is 90 sec)?'};
            app.MedianSmoothWindowNumericEditField = uieditfield(app.EnvelopeViewerTab, 'numeric');
            app.MedianSmoothWindowNumericEditField.Value = 90;
            app.QuantifyenvelopesButtonGroup = uibuttongroup(app.EnvelopeViewerTab);
            app.QuantifyenvelopesButtonGroup.Title = 'Quantify envelopes:';
            app.ByHourButton = uiradiobutton(app.QuantifyenvelopesButtonGroup);
            app.ByHourButton.Text = 'ByHour';
            app.ByHourButton.Value = true;
            app.ByStageButton = uiradiobutton(app.QuantifyenvelopesButtonGroup);
            app.ByStageButton.Text = 'ByStage';
            app.AverageenvelopesacrossButtonGroup = uibuttongroup(app.EnvelopeViewerTab);
            app.AverageenvelopesacrossButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AverageenvelopesacrossButtonGroupSelectionChanged, true);
            app.AverageenvelopesacrossButtonGroup.Title = 'Average envelopes across:';
            app.chansButton = uiradiobutton(app.AverageenvelopesacrossButtonGroup);
            app.chansButton.Text = 'chans';
            app.chansButton.Value = true;
            app.timeButton = uiradiobutton(app.AverageenvelopesacrossButtonGroup);
            app.timeButton.Text = 'time';
            app.WhatenvelopetypeButtonGroup = uibuttongroup(app.EnvelopeViewerTab);
            app.WhatenvelopetypeButtonGroup.Title = 'What envelope type?';
            app.amplitudeButton = uiradiobutton(app.WhatenvelopetypeButtonGroup);
            app.amplitudeButton.Text = 'amplitude';
            app.amplitudeButton.Value = true;
            app.powerButton = uiradiobutton(app.WhatenvelopetypeButtonGroup);
            app.powerButton.Text = 'power';
            app.CollateorloadenvelopesButtonGroup = uibuttongroup(app.EnvelopeViewerTab);
            app.CollateorloadenvelopesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CollateorloadenvelopesButtonGroupSelectionChanged, true);
            app.CollateorloadenvelopesButtonGroup.Title = 'Collate or load envelopes?';
            app.CollateButton = uiradiobutton(app.CollateorloadenvelopesButtonGroup);
            app.CollateButton.Text = 'Collate';
            app.CollateButton.Value = true;
            app.LoadButton = uiradiobutton(app.CollateorloadenvelopesButtonGroup);
            app.LoadButton.Text = 'Load';
            app.EnvViewerTempROILowNumericEditFieldLabel = uilabel(app.EnvelopeViewerTab);
            app.EnvViewerTempROILowNumericEditFieldLabel.Text = {'What time interval (min) should'; 'envelopes be averaged over'; '(type 999 in either for all)?'};
            app.EnvViewerTempROILowNumericEditField = uieditfield(app.EnvelopeViewerTab, 'numeric');
            app.EnvViewerTempROIHighNumericEditFieldLabel = uilabel(app.EnvelopeViewerTab);
            app.EnvViewerTempROIHighNumericEditFieldLabel.Text = 'to';
            app.EnvViewerTempROIHighNumericEditField = uieditfield(app.EnvelopeViewerTab, 'numeric');
            app.EnvViewerTempROIHighNumericEditField.Value = 60;
            app.OmitEmptyEnvelopesCheckBox = uicheckbox(app.EnvelopeViewerTab);
            app.OmitEmptyEnvelopesCheckBox.Text = 'Omit empty plots for EEG files without envelope files?';
            app.ThetaFilterModeDropDown_EnvViewerLabel = uilabel(app.EnvelopeViewerTab);
            app.ThetaFilterModeDropDown_EnvViewerLabel.Text = 'Theta Filter Mode';
            app.ThetaFilterModeDropDown_EnvViewer = uidropdown(app.EnvelopeViewerTab);
            app.ThetaFilterModeDropDown_EnvViewer.Items = {'FIR', 'cheby1', 'cheby2', 'none'};
            app.ThetaFilterModeDropDown_EnvViewer.Value = 'none';
            app.ModulespecificParametersLabel = uilabel(app.Step2SelectsignalprocessingapproachPanel);
            app.ModulespecificParametersLabel.Text = 'Module-specific Parameters';
            app.AnalysisModuleselectoneDropDownLabel = uilabel(app.Step2SelectsignalprocessingapproachPanel);
            app.AnalysisModuleselectoneDropDownLabel.Text = 'Analysis Module (select one)';
            app.AnalysisModuleselectoneDropDown = uidropdown(app.Step2SelectsignalprocessingapproachPanel);
            app.AnalysisModuleselectoneDropDown.Items = {' ', 'Hypnogram', 'Spectra (EEGLAB)', 'Spectra (FieldTrip)', 'Power (Hilbert)', 'Power (newtimef)', 'Spindle Detection (Ferrarelli)', 'Envelope Viewer'};
            app.AnalysisModuleselectoneDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisModuleselectoneDropDownValueChanged, true);
            app.AnalysisModuleselectoneDropDown.Value = 'Envelope Viewer';
            app.Step3SelectbandsandstagesPanel = uipanel(app.UIFigure);
            app.Step3SelectbandsandstagesPanel.Title = 'Step 3: Select bands and stages';
            app.Step3SelectbandsandstagesPanel.SizeChangedFcn = createCallbackFcn(app, @SubdeltaCheckBoxValueChanged, true);
            app.SubdeltaCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SubdeltaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubdeltaCheckBoxValueChanged, true);
            app.SubdeltaCheckBox.Enable = 'off';
            app.SubdeltaCheckBox.Text = 'Subdelta';
            app.DeltaCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.DeltaCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeltaCheckBoxValueChanged, true);
            app.DeltaCheckBox.Enable = 'off';
            app.DeltaCheckBox.Text = 'Delta';
            app.ThetaCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.ThetaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThetaCheckBoxValueChanged, true);
            app.ThetaCheckBox.Enable = 'off';
            app.ThetaCheckBox.Text = 'Theta';
            app.AlphaCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.AlphaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlphaCheckBoxValueChanged, true);
            app.AlphaCheckBox.Enable = 'off';
            app.AlphaCheckBox.Text = 'Alpha';
            app.SigmaCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SigmaCheckBoxValueChanged, true);
            app.SigmaCheckBox.Enable = 'off';
            app.SigmaCheckBox.Text = 'Sigma';
            app.BetaCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.BetaCheckBox.ValueChangedFcn = createCallbackFcn(app, @BetaCheckBoxValueChanged, true);
            app.BetaCheckBox.Enable = 'off';
            app.BetaCheckBox.Text = 'Beta';
            app.GammalowCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.GammalowCheckBox.ValueChangedFcn = createCallbackFcn(app, @GammalowCheckBoxValueChanged, true);
            app.GammalowCheckBox.Enable = 'off';
            app.GammalowCheckBox.Text = 'Gamma (low)';
            app.FullbandCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.FullbandCheckBox.ValueChangedFcn = createCallbackFcn(app, @FullbandCheckBoxValueChanged, true);
            app.FullbandCheckBox.Enable = 'off';
            app.FullbandCheckBox.Text = 'Full Band';
            app.EEGBandsLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.EEGBandsLabel.Text = 'EEG Bands';
            app.FrequencyBandwidthLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.FrequencyBandwidthLabel.Text = 'Frequency Bandwidth';
            app.SleepStagesLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.SleepStagesLabel.Text = 'Sleep Stages';
            app.SubdeltaLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SubdeltaLowLimFreq.Enable = 'off';
            app.SubdeltaLowLimFreq.Value = 0.1;
            app.DeltaLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.DeltaLowLimFreq.Enable = 'off';
            app.DeltaLowLimFreq.Value = 0.5;
            app.ThetaLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.ThetaLowLimFreq.Enable = 'off';
            app.ThetaLowLimFreq.Value = 4;
            app.AlphaLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.AlphaLowLimFreq.Enable = 'off';
            app.AlphaLowLimFreq.Value = 8;
            app.SigmaLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SigmaLowLimFreq.Enable = 'off';
            app.SigmaLowLimFreq.Value = 12;
            app.BetaLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.BetaLowLimFreq.Enable = 'off';
            app.BetaLowLimFreq.Value = 16;
            app.GammalowLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.GammalowLowLimFreq.Enable = 'off';
            app.GammalowLowLimFreq.Value = 30;
            app.FullbandLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.FullbandLowLimFreq.RoundFractionalValues = 'on';
            app.FullbandLowLimFreq.Enable = 'off';
            app.SubdeltatoLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.SubdeltatoLabel.Text = 'to';
            app.DeltatoLabel_2 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.DeltatoLabel_2.Text = 'to';
            app.ThetatoLabel_3 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.ThetatoLabel_3.Text = 'to';
            app.AlphatoLabel_4 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.AlphatoLabel_4.Text = 'to';
            app.SigmatoLabel_5 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.SigmatoLabel_5.Text = 'to';
            app.BetatoLabel_6 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.BetatoLabel_6.Text = 'to';
            app.GammalowtoLabel_7 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.GammalowtoLabel_7.Text = 'to';
            app.FullbandtoLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.FullbandtoLabel.Text = 'to';
            app.SubdeltaHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SubdeltaHighLimFreq.Enable = 'off';
            app.SubdeltaHighLimFreq.Value = 0.5;
            app.DeltaHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.DeltaHighLimFreq.Enable = 'off';
            app.DeltaHighLimFreq.Value = 4;
            app.ThetaHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.ThetaHighLimFreq.Enable = 'off';
            app.ThetaHighLimFreq.Value = 8;
            app.AlphaHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.AlphaHighLimFreq.Enable = 'off';
            app.AlphaHighLimFreq.Value = 12;
            app.SigmaHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SigmaHighLimFreq.Enable = 'off';
            app.SigmaHighLimFreq.Value = 16;
            app.BetaHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.BetaHighLimFreq.Enable = 'off';
            app.BetaHighLimFreq.Value = 30;
            app.GammalowHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.GammalowHighLimFreq.Enable = 'off';
            app.GammalowHighLimFreq.Value = 50;
            app.FullbandHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.FullbandHighLimFreq.Enable = 'off';
            app.FullbandHighLimFreq.Value = 80;
            app.HzLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_3.Text = 'Hz';
            app.HzLabel_4 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_4.Text = 'Hz';
            app.HzLabel_5 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_5.Text = 'Hz';
            app.HzLabel_6 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_6.Text = 'Hz';
            app.HzLabel_7 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_7.Text = 'Hz';
            app.HzLabel_9 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_9.Text = 'Hz';
            app.SubdeltaN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SubdeltaN1.Enable = 'off';
            app.SubdeltaN1.Text = '';
            app.DeltaN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.DeltaN1.Enable = 'off';
            app.DeltaN1.Text = '';
            app.ThetaN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.ThetaN1.Enable = 'off';
            app.ThetaN1.Text = '';
            app.AlphaN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.AlphaN1.Enable = 'off';
            app.AlphaN1.Text = '';
            app.SigmaN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SigmaN1.Enable = 'off';
            app.SigmaN1.Text = '';
            app.BetaN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.BetaN1.Enable = 'off';
            app.BetaN1.Text = '';
            app.GammalowN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.GammalowN1.Enable = 'off';
            app.GammalowN1.Text = '';
            app.FullbandN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.FullbandN1.Enable = 'off';
            app.FullbandN1.Text = '';
            app.N1Label = uilabel(app.Step3SelectbandsandstagesPanel);
            app.N1Label.Text = 'N1';
            app.SubdeltaN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SubdeltaN2.Enable = 'off';
            app.SubdeltaN2.Text = '';
            app.DeltaN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.DeltaN2.Enable = 'off';
            app.DeltaN2.Text = '';
            app.ThetaN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.ThetaN2.Enable = 'off';
            app.ThetaN2.Text = '';
            app.AlphaN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.AlphaN2.Enable = 'off';
            app.AlphaN2.Text = '';
            app.SigmaN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SigmaN2.Enable = 'off';
            app.SigmaN2.Text = '';
            app.BetaN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.BetaN2.Enable = 'off';
            app.BetaN2.Text = '';
            app.GammalowN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.GammalowN2.Enable = 'off';
            app.GammalowN2.Text = '';
            app.FullbandN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.FullbandN2.Enable = 'off';
            app.FullbandN2.Text = '';
            app.N2Label = uilabel(app.Step3SelectbandsandstagesPanel);
            app.N2Label.Text = 'N2';
            app.SubdeltaN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SubdeltaN3.Enable = 'off';
            app.SubdeltaN3.Text = '';
            app.DeltaN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.DeltaN3.Enable = 'off';
            app.DeltaN3.Text = '';
            app.ThetaN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.ThetaN3.Enable = 'off';
            app.ThetaN3.Text = '';
            app.AlphaN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.AlphaN3.Enable = 'off';
            app.AlphaN3.Text = '';
            app.SigmaN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SigmaN3.Enable = 'off';
            app.SigmaN3.Text = '';
            app.BetaN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.BetaN3.Enable = 'off';
            app.BetaN3.Text = '';
            app.GammalowN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.GammalowN3.Enable = 'off';
            app.GammalowN3.Text = '';
            app.FullbandN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.FullbandN3.Enable = 'off';
            app.FullbandN3.Text = '';
            app.N3Label = uilabel(app.Step3SelectbandsandstagesPanel);
            app.N3Label.Text = 'N3';
            app.SubdeltaR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SubdeltaR.Enable = 'off';
            app.SubdeltaR.Text = '';
            app.DeltaR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.DeltaR.Enable = 'off';
            app.DeltaR.Text = '';
            app.ThetaR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.ThetaR.Enable = 'off';
            app.ThetaR.Text = '';
            app.AlphaR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.AlphaR.Enable = 'off';
            app.AlphaR.Text = '';
            app.SigmaR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SigmaR.Enable = 'off';
            app.SigmaR.Text = '';
            app.BetaR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.BetaR.Enable = 'off';
            app.BetaR.Text = '';
            app.GammalowR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.GammalowR.Enable = 'off';
            app.GammalowR.Text = '';
            app.FullbandR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.FullbandR.Enable = 'off';
            app.FullbandR.Text = '';
            app.RLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.RLabel.Text = 'R';
            app.SubdeltaW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SubdeltaW.Enable = 'off';
            app.SubdeltaW.Text = '';
            app.DeltaW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.DeltaW.Enable = 'off';
            app.DeltaW.Text = '';
            app.ThetaW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.ThetaW.Enable = 'off';
            app.ThetaW.Text = '';
            app.AlphaW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.AlphaW.Enable = 'off';
            app.AlphaW.Text = '';
            app.SigmaW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SigmaW.Enable = 'off';
            app.SigmaW.Text = '';
            app.BetaW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.BetaW.Enable = 'off';
            app.BetaW.Text = '';
            app.GammalowW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.GammalowW.Enable = 'off';
            app.GammalowW.Text = '';
            app.FullbandW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.FullbandW.Enable = 'off';
            app.FullbandW.Text = '';
            app.WLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.WLabel.Text = 'W';
            app.SubdeltaN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SubdeltaN4.Enable = 'off';
            app.SubdeltaN4.Text = '';
            app.DeltaN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.DeltaN4.Enable = 'off';
            app.DeltaN4.Text = '';
            app.ThetaN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.ThetaN4.Enable = 'off';
            app.ThetaN4.Text = '';
            app.AlphaN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.AlphaN4.Enable = 'off';
            app.AlphaN4.Text = '';
            app.SigmaN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SigmaN4.Enable = 'off';
            app.SigmaN4.Text = '';
            app.BetaN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.BetaN4.Enable = 'off';
            app.BetaN4.Text = '';
            app.GammalowN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.GammalowN4.Enable = 'off';
            app.GammalowN4.Text = '';
            app.FullbandN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.FullbandN4.Enable = 'off';
            app.FullbandN4.Text = '';
            app.N4Label = uilabel(app.Step3SelectbandsandstagesPanel);
            app.N4Label.Text = 'N4';
            app.ArtifactThresholdLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.ArtifactThresholdLabel.Text = 'Artifact Threshold';
            app.SubdeltaArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SubdeltaArtifact.Enable = 'off';
            app.SubdeltaArtifact.Value = 250;
            app.DeltaArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.DeltaArtifact.Enable = 'off';
            app.DeltaArtifact.Value = 250;
            app.ThetaArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.ThetaArtifact.Enable = 'off';
            app.ThetaArtifact.Value = 150;
            app.AlphaArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.AlphaArtifact.Enable = 'off';
            app.AlphaArtifact.Value = 100;
            app.SigmaArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SigmaArtifact.ValueChangedFcn = createCallbackFcn(app, @SigmaCheckBoxValueChanged, true);
            app.SigmaArtifact.Enable = 'off';
            app.SigmaArtifact.Value = 75;
            app.BetaArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.BetaArtifact.Enable = 'off';
            app.BetaArtifact.Value = 75;
            app.GammalowArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.GammalowArtifact.Enable = 'off';
            app.GammalowArtifact.Value = 75;
            app.FullbandArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.FullbandArtifact.Enable = 'off';
            app.SleepbandCheckBox = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SleepbandCheckBox.ValueChangedFcn = createCallbackFcn(app, @SleepbandCheckBoxValueChanged, true);
            app.SleepbandCheckBox.Enable = 'off';
            app.SleepbandCheckBox.Text = 'Sleep Band';
            app.SleepbandLowLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SleepbandLowLimFreq.Enable = 'off';
            app.SleepbandLowLimFreq.Value = 0.5;
            app.SleepbandtoLabel = uilabel(app.Step3SelectbandsandstagesPanel);
            app.SleepbandtoLabel.Text = 'to';
            app.SleepbandHighLimFreq = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SleepbandHighLimFreq.Enable = 'off';
            app.SleepbandHighLimFreq.Value = 20;
            app.HzLabel_10 = uilabel(app.Step3SelectbandsandstagesPanel);
            app.HzLabel_10.Text = 'Hz';
            app.SleepbandN1 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SleepbandN1.Enable = 'off';
            app.SleepbandN1.Text = '';
            app.SleepbandN2 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SleepbandN2.Enable = 'off';
            app.SleepbandN2.Text = '';
            app.SleepbandN3 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SleepbandN3.Enable = 'off';
            app.SleepbandN3.Text = '';
            app.SleepbandR = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SleepbandR.Enable = 'off';
            app.SleepbandR.Text = '';
            app.SleepbandW = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SleepbandW.Enable = 'off';
            app.SleepbandW.Text = '';
            app.SleepbandN4 = uicheckbox(app.Step3SelectbandsandstagesPanel);
            app.SleepbandN4.Enable = 'off';
            app.SleepbandN4.Text = '';
            app.SleepbandArtifact = uieditfield(app.Step3SelectbandsandstagesPanel, 'numeric');
            app.SleepbandArtifact.Enable = 'off';
            app.AllStagesButton = uibutton(app.Step3SelectbandsandstagesPanel, 'push');
            app.AllStagesButton.ButtonPushedFcn = createCallbackFcn(app, @AllStagesButtonPushed, true);
            app.AllStagesButton.Text = 'All Stages';
            app.AllBandsButton = uibutton(app.Step3SelectbandsandstagesPanel, 'push');
            app.AllBandsButton.ButtonPushedFcn = createCallbackFcn(app, @AllBandsButtonPushed, true);
            app.AllBandsButton.Text = 'All Bands';
            app.Step4OthersettingsPanel = uipanel(app.UIFigure);
            app.Step4OthersettingsPanel.Title = 'Step 4: Other settings';
            app.ForcerecreationofEEGLABsetfilesCheckBox = uicheckbox(app.Step4OthersettingsPanel);
            app.ForcerecreationofEEGLABsetfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcerecreationofEEGLABsetfilesCheckBoxValueChanged, true);
            app.ForcerecreationofEEGLABsetfilesCheckBox.Text = 'Force recreation of EEGLAB .set files';
            app.ForcerecreationofEEGLABsetfilesCheckBox.Value = true;
            app.DownsampletoHzCheckBox = uicheckbox(app.Step4OthersettingsPanel);
            app.DownsampletoHzCheckBox.Text = 'Downsample to                Hz';
            app.SampRateisHighLabel = uilabel(app.Step4OthersettingsPanel);
            app.SampRateisHighLabel.Text = {'EEG sample rate is high. You may want to'; 'downsample to a lower sampling frequency to'; 'improve compute times.'; ''};
            app.RereferenceEEGDatatoButtonGroup = uibuttongroup(app.Step4OthersettingsPanel);
            app.RereferenceEEGDatatoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RereferenceEEGDatatoButtonGroupSelectionChanged, true);
            app.RereferenceEEGDatatoButtonGroup.Title = 'Re-reference EEG Data to...';
            app.AvgMastButton = uiradiobutton(app.RereferenceEEGDatatoButtonGroup);
            app.AvgMastButton.Text = 'AvgMast';
            app.AvgMastButton.Value = true;
            app.ContraMastButton = uiradiobutton(app.RereferenceEEGDatatoButtonGroup);
            app.ContraMastButton.Text = 'ContraMast';
            app.CustomRerefButton = uiradiobutton(app.RereferenceEEGDatatoButtonGroup);
            app.CustomRerefButton.Text = 'Custom';
            app.CustomRerefEditField = uieditfield(app.RereferenceEEGDatatoButtonGroup, 'text');
            app.CustomRerefEditField.ValueChangedFcn = createCallbackFcn(app, @ForcerecreationofEEGLABsetfilesCheckBoxValueChanged, true);
            app.CustomRerefEditField.Enable = 'off';
            app.RunandsaveICAdecompositionsCheckBox = uicheckbox(app.Step4OthersettingsPanel);
            app.RunandsaveICAdecompositionsCheckBox.Text = 'Run and save ICA decompositions?';
            app.InterpolatemanuallyidentifiedbadchannelsCheckBox = uicheckbox(app.Step4OthersettingsPanel);
            app.InterpolatemanuallyidentifiedbadchannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolatemanuallyidentifiedbadchannelsCheckBoxValueChanged, true);
            app.InterpolatemanuallyidentifiedbadchannelsCheckBox.Text = 'Interpolate manually identified bad channels?';
            app.DownsampledFrequencyNumericEditField = uieditfield(app.Step4OthersettingsPanel, 'numeric');
            app.DownsampledFrequencyNumericEditField.Value = 500;
            app.ARBCISettingsLampLabel = uilabel(app.Step4OthersettingsPanel);
            app.ARBCISettingsLampLabel.Text = 'BCI Settings';
            app.ARBCISettingsLamp = uilamp(app.Step4OthersettingsPanel);
            app.ARBCISettingsButton = uibutton(app.Step4OthersettingsPanel, 'push');
            app.ARBCISettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ARBCISettingsButtonPush, true);
            app.ARBCISettingsButton.Text = 'Import';
            app.PreprocessingSettingsButton = uibutton(app.Step4OthersettingsPanel, 'push');
            app.PreprocessingSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingSettingsButtonPush, true);
            app.PreprocessingSettingsButton.Text = 'Import';
            app.PreProcessingSettingsLampLabel = uilabel(app.Step4OthersettingsPanel);
            app.PreProcessingSettingsLampLabel.Text = 'Preprocessing Settings';
            app.PreProcessingSettingsLamp = uilamp(app.Step4OthersettingsPanel);
            app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox = uicheckbox(app.Step4OthersettingsPanel);
            app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Visible = 'off';
            app.InterpolateextrathetabadchannelsMRISRTonlyCheckBox.Text = 'Interpolate extra theta bad channels? (MRI-SRT only)';
            app.ImageModeButtonGroup = uibuttongroup(app.Step4OthersettingsPanel);
            app.ImageModeButtonGroup.Title = 'Image Mode';
            app.AutocloseButton = uiradiobutton(app.ImageModeButtonGroup);
            app.AutocloseButton.Text = 'Autoclose';
            app.AutocloseButton.Value = true;
            app.PauseButton = uiradiobutton(app.ImageModeButtonGroup);
            app.PauseButton.Text = 'Pause';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.ImportPSGpowerParametersButton = uibutton(app.UIFigure, 'push');
            app.ImportPSGpowerParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPSGpowerParameterSettingsMenuSelected, true);
            app.ImportPSGpowerParametersButton.Text = 'Import PSGpower Parameters';
            app.ExportPSGpowerParametersButton = uibutton(app.UIFigure, 'push');
            app.ExportPSGpowerParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPSGpowerParameterSettingsMenuSelected, true);
            app.ExportPSGpowerParametersButton.Text = 'Export PSGpower Parameters';
            app.ExperimentnameDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentnameDropDownLabel.Text = 'Experiment name:';
            app.ExperimentnameDropDown = uidropdown(app.UIFigure);
            app.ExperimentnameDropDown.Items = {'Custom', '<Input SNL experiment name>'};
            app.ExperimentnameDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentnameDropDownValueChanged, true);
            app.ExperimentnameDropDown.Value = 'Custom';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PSGpower_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end